classdef satplan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MasterGridLayout                matlab.ui.container.GridLayout
        MasterPlanOverviewPanel         matlab.ui.container.Panel
        MasterPlanGridLayout            matlab.ui.container.GridLayout
        MasterPlanRemoveButton          matlab.ui.control.Button
        MasterPlanJSONPreview           matlab.ui.control.TextArea
        MasterPlanTable                 matlab.ui.control.Table
        MasterUIScaleSlider             matlab.ui.control.Slider
        MasterTabGroup                  matlab.ui.container.TabGroup
        SatelliteTab                    matlab.ui.container.Tab
        SatelliteTabLayout              matlab.ui.container.GridLayout
        AddSatellitePanel               matlab.ui.container.Panel
        AddSatellitePanelLayout         matlab.ui.container.GridLayout
        SatellitePresetAddButton        matlab.ui.control.Button
        SatellitePresetDropDown         matlab.ui.control.DropDown
        PresetDropDownLabel             matlab.ui.control.Label
        TLEFromURLSearchTextArea        matlab.ui.control.TextArea
        SearchTextArea_2Label           matlab.ui.control.Label
        TLEFromURLEditField             matlab.ui.control.EditField
        TLEURLEditFieldLabel            matlab.ui.control.Label
        TLEFromURLLoadButton            matlab.ui.control.Button
        TLEFromFileSearchTextArea       matlab.ui.control.TextArea
        SearchTextAreaLabel             matlab.ui.control.Label
        TLEFromFileOpenButton           matlab.ui.control.Button
        TLEFileEditField                matlab.ui.control.EditField
        TLEFileEditFieldLabel           matlab.ui.control.Label
        LoadedSatellitesPanel           matlab.ui.container.Panel
        LoadedSatellitesPanelLayout     matlab.ui.container.GridLayout
        LoadedSatellitesDeleteButton    matlab.ui.control.Button
        LoadedSatellitesExportButton    matlab.ui.control.Button
        LoadedSatellitesLoadButton      matlab.ui.control.Button
        LoadedSatellitesTable           matlab.ui.control.Table
        SatelliteTabStatusLabel         matlab.ui.control.Label
        SatelliteTabUIVerticalSlider    matlab.ui.control.Slider
        SatelliteTabUIHorizontalSlider  matlab.ui.control.Slider
        SatelliteInfoPanel              matlab.ui.container.Panel
        SatelliteInfoPanelLayout        matlab.ui.container.GridLayout
        SatelliteInfoIgnoreChecksumsCheckBox  matlab.ui.control.CheckBox
        SatelliteInfoAutogenerateChecksumsCheckBox  matlab.ui.control.CheckBox
        SatelliteInfoNameEditField      matlab.ui.control.EditField
        SatelliteNameEditFieldLabel     matlab.ui.control.Label
        TLELine2Panel                   matlab.ui.container.Panel
        TLELine2Layout                  matlab.ui.container.GridLayout
        Checksummodulo10EditField_2     matlab.ui.control.EditField
        Checksummodulo10EditField_2Label  matlab.ui.control.Label
        RevolutionnoatEpochEditField    matlab.ui.control.EditField
        RevolutionnoatEpochEditFieldLabel  matlab.ui.control.Label
        MeanMotionrevdayEditField       matlab.ui.control.EditField
        MeanMotionrevdayEditFieldLabel  matlab.ui.control.Label
        MeanAnomalydegEditField         matlab.ui.control.EditField
        MeanAnomalydegEditFieldLabel    matlab.ui.control.Label
        ArgumentofPerigeedegEditField   matlab.ui.control.EditField
        ArgumentofPerigeedegEditFieldLabel  matlab.ui.control.Label
        EccentricityEditField           matlab.ui.control.EditField
        EccentricityEditFieldLabel      matlab.ui.control.Label
        RAANdegEditField                matlab.ui.control.EditField
        RAANdegEditFieldLabel           matlab.ui.control.Label
        InclinationdegEditField         matlab.ui.control.EditField
        InclinationdegEditFieldLabel    matlab.ui.control.Label
        SatelliteCatalogNumberEditField_2  matlab.ui.control.EditField
        SatelliteCatalogNumberEditField_2Label  matlab.ui.control.Label
        TLELine1Panel                   matlab.ui.container.Panel
        TLELine1Layout                  matlab.ui.container.GridLayout
        Checksummodulo10EditField       matlab.ui.control.EditField
        Checksummodulo10EditFieldLabel  matlab.ui.control.Label
        ElementSetNumberEditField       matlab.ui.control.EditField
        ElementSetNumberEditFieldLabel  matlab.ui.control.Label
        EphemerisTypeEditField          matlab.ui.control.EditField
        EphemerisTypeEditFieldLabel     matlab.ui.control.Label
        RadiationPressureCoefficientEditField  matlab.ui.control.EditField
        RadiationPressureCoefficientEditFieldLabel  matlab.ui.control.Label
        SecondDerivofMeanMotionEditField  matlab.ui.control.EditField
        SecondDerivofMeanMotionEditFieldLabel  matlab.ui.control.Label
        FirstDerivofMeanMotionEditField  matlab.ui.control.EditField
        FirstDerivofMeanMotionEditFieldLabel  matlab.ui.control.Label
        EpochDayEditField               matlab.ui.control.EditField
        EpochDayEditFieldLabel          matlab.ui.control.Label
        EpochYearEditField              matlab.ui.control.EditField
        EpochYearEditFieldLabel         matlab.ui.control.Label
        IntlDesignatorpieceEditField    matlab.ui.control.EditField
        IntlDesignatorpieceEditFieldLabel  matlab.ui.control.Label
        IntlDesignatorlaunchnoEditField  matlab.ui.control.EditField
        IntlDesignatorlaunchnoEditFieldLabel  matlab.ui.control.Label
        IntlDesignatorlaunchyrEditField  matlab.ui.control.EditField
        IntlDesignatorlaunchyrEditFieldLabel  matlab.ui.control.Label
        ClassificationEditField         matlab.ui.control.EditField
        ClassificationEditFieldLabel    matlab.ui.control.Label
        SatelliteCatalogNumberEditField  matlab.ui.control.EditField
        SatelliteCatalogNumberEditFieldLabel  matlab.ui.control.Label
        PropagationTab                  matlab.ui.container.Tab
        SGP4TabGridLayout               matlab.ui.container.GridLayout
        SGP4TabStatusLabel              matlab.ui.control.Label
        SGP4TabUIHorizontalSlider       matlab.ui.control.Slider
        SGP4TabUIVerticalSlider         matlab.ui.control.Slider
        SGP4PropagatorOptionsPanel      matlab.ui.container.Panel
        SGP4PropagatorOptionsPanelGridLayout  matlab.ui.container.GridLayout
        PropagatorDropDown              matlab.ui.control.DropDown
        PropagatorDropDownLabel         matlab.ui.control.Label
        SGP4PropagatorUTCOffsetEditField  matlab.ui.control.EditField
        UTCOffsetLabel                  matlab.ui.control.Label
        SGP4PropagatorStartDateDatePicker  matlab.ui.control.DatePicker
        StartDateDatePickerLabel        matlab.ui.control.Label
        SGP4PropagatorEndDateDatePicker  matlab.ui.control.DatePicker
        EndDateDatePickerLabel          matlab.ui.control.Label
        SGP4PropagatorTimestepUnitsDropDown  matlab.ui.control.DropDown
        SGP4PropagatorStartTimeEditField  matlab.ui.control.EditField
        StartTimeEditFieldLabel         matlab.ui.control.Label
        SGP4PropagatorEndTimeEditField  matlab.ui.control.EditField
        EndTimeEditFieldLabel           matlab.ui.control.Label
        SGP4PropagatorTimestepEditField  matlab.ui.control.NumericEditField
        TimestepEditFieldLabel          matlab.ui.control.Label
        SGP4PropagatorPropagateButton   matlab.ui.control.Button
        SGP4GroundtrackPlotPanel        matlab.ui.container.Panel
        SGP4TablePanel                  matlab.ui.container.Panel
        SGP4TableGridLayout             matlab.ui.container.GridLayout
        AddEphemerisButton              matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        DisplayDropDown                 matlab.ui.control.DropDown
        DisplayDropDownLabel            matlab.ui.control.Label
        SGP4TableHighlightEclipseCheckBox  matlab.ui.control.CheckBox
        SGP4TableHighlightSAACheckBox   matlab.ui.control.CheckBox
        SGP4TableTimestampFormatDropDown  matlab.ui.control.DropDown
        TimestampDropDownLabel          matlab.ui.control.Label
        SGP4TableUTCOffsetEditField     matlab.ui.control.EditField
        UTCOffsetEditField_2Label       matlab.ui.control.Label
        SGP4TableCoordinatesFormatDropDown  matlab.ui.control.DropDown
        CoordinatesDropDownLabel        matlab.ui.control.Label
        SGP4TableShowStepIndexesCheckBox  matlab.ui.control.CheckBox
        SGP4Table                       matlab.ui.control.Table
        GroundStationsTab               matlab.ui.container.Tab
        GroundStationsTabGridLayout     matlab.ui.container.GridLayout
        GroundStationsTabStatusLabel    matlab.ui.control.Label
        GroundStationsTabUIHorizontalSlider  matlab.ui.control.Slider
        GroundStationsTabUIVerticalSlider  matlab.ui.control.Slider
        PassoverEventsTablePanel        matlab.ui.container.Panel
        PassoverEventsTablePanelGridLayout  matlab.ui.container.GridLayout
        PassoverTableShowStepIndexesCheckBox  matlab.ui.control.CheckBox
        GridLayout14                    matlab.ui.container.GridLayout
        PassoverEventsTableAddAllButton  matlab.ui.control.Button
        PassoverEventsTableAddtoPlanButton  matlab.ui.control.Button
        PassoverTableDisplayasDropDown  matlab.ui.control.DropDown
        DisplayasDropDown_2Label        matlab.ui.control.Label
        PassoverTableUTCOffsetEditField  matlab.ui.control.EditField
        UTCOffsetLabel_2                matlab.ui.control.Label
        PassoverTableTimestampFormatDropDown  matlab.ui.control.DropDown
        TimestampDropDownLabel_2        matlab.ui.control.Label
        ShowNonPassoverWindowsCheckBox  matlab.ui.control.CheckBox
        PassoverEventsTable             matlab.ui.control.Table
        PassoverOptionsPanel            matlab.ui.container.Panel
        PassoverOptionsPanelGridLayout  matlab.ui.container.GridLayout
        PassoverOptionsAdvancedOptionsButton  matlab.ui.control.Button
        PassoverOptionsIgnorePassoversUnitsDropDown  matlab.ui.control.DropDown
        PassoverOptionsIgnorePassoversEditField  matlab.ui.control.NumericEditField
        PassoverOptionsElevationConstraintUnitsDropDown  matlab.ui.control.DropDown
        PassoverOptionsAzimuthConstraintUnitsDropDown  matlab.ui.control.DropDown
        PassoverOptionsCalculatePassoversButton  matlab.ui.control.Button
        PassoverOptionsIgnorePassoversCheckBox  matlab.ui.control.CheckBox
        PassoverOptionsAzimuthConstraintEditField  matlab.ui.control.NumericEditField
        AzimuthConstraintLabel          matlab.ui.control.Label
        PassoverOptionsElevationConstraintEditField  matlab.ui.control.NumericEditField
        ElevationConstraintLabel        matlab.ui.control.Label
        GroundStationsInfoPanel         matlab.ui.container.Panel
        GroundStationsInfoPanelGridLayout  matlab.ui.container.GridLayout
        GroundStationsInfoTableSaveAsButton  matlab.ui.control.Button
        GroundStationsInfoTableLoadButton  matlab.ui.control.Button
        GroundStationsInfoTable         matlab.ui.control.Table
        TargetsTab                      matlab.ui.container.Tab
        TargetsTabGridLayout            matlab.ui.container.GridLayout
        TargetsTabStatusLabel           matlab.ui.control.Label
        TargetsTabUIVerticalSlider      matlab.ui.control.Slider
        TargetsTabUIHorizontalSlider    matlab.ui.control.Slider
        TargetObservationWindowsTablePanel  matlab.ui.container.Panel
        TargetObservationWindowsTablePanelGridLayout  matlab.ui.container.GridLayout
        TargetWindowsTableShowStepIndexesCheckBox  matlab.ui.control.CheckBox
        TargetWindowsTableDisplayasDropDown  matlab.ui.control.DropDown
        DisplayasDropDownLabel          matlab.ui.control.Label
        TargetWindowsTableUTCOffsetEditField  matlab.ui.control.EditField
        UTCOffsetLabel_3                matlab.ui.control.Label
        TargetWindowsTableTimestampFormatDropDown  matlab.ui.control.DropDown
        TimestampDropDownLabel_3        matlab.ui.control.Label
        TargetObservationWindowsTable   matlab.ui.control.Table
        TargetSchedulerOptionsPanel     matlab.ui.container.Panel
        TargetSchedulerOptionsPanelGridLayout  matlab.ui.container.GridLayout
        TargetOptionsSunConstraintEditField  matlab.ui.control.NumericEditField
        SunConstraintEditFieldLabel     matlab.ui.control.Label
        TargetOptionsMoonConstraintEditField  matlab.ui.control.NumericEditField
        MoonConstraintEditFieldLabel    matlab.ui.control.Label
        TargetOptionsElevationConstraintEditField  matlab.ui.control.NumericEditField
        ElevationConstraintEditFieldLabel  matlab.ui.control.Label
        TargetOptionsDayNightConstraintEditField  matlab.ui.control.NumericEditField
        DayNightConstraintEditFieldLabel  matlab.ui.control.Label
        TargetOptionsIgnoreWindowsUnitsDropDown  matlab.ui.control.DropDown
        TargetOptionsIgnoreWindowsEditField  matlab.ui.control.NumericEditField
        TargetOptionsIgnoreWindowsCheckBox  matlab.ui.control.CheckBox
        TargetSchedulerCalculateVisibilityButton  matlab.ui.control.Button
        TargetInfoPanel                 matlab.ui.container.Panel
        TargetInfoPanelGridLayout       matlab.ui.container.GridLayout
        TargetInfoDeleteButton          matlab.ui.control.Button
        TargetInfoSaveAsButton          matlab.ui.control.Button
        TargetInfoLoadButton            matlab.ui.control.Button
        TargetInfoTable                 matlab.ui.control.Table
        ExposuresTab                    matlab.ui.container.Tab
        ExposuresTabGridLayout          matlab.ui.container.GridLayout
        ExposuresTabStatusLabel         matlab.ui.control.Label
        Slider_2                        matlab.ui.control.Slider
        Slider                          matlab.ui.control.Slider
        ExposuresAddedTablePanel        matlab.ui.container.Panel
        ExposuresAddedTablePanelGridLayout  matlab.ui.container.GridLayout
        ExposuresAddedDisplayasDropDown  matlab.ui.control.DropDown
        DisplayasDropDown_3Label        matlab.ui.control.Label
        ExposuresAddedUTCOffsetEditField  matlab.ui.control.EditField
        UTCOffsetLabel_4                matlab.ui.control.Label
        ExposuresAddedTimestampFormatDropDown  matlab.ui.control.DropDown
        TimestampDropDownLabel_4        matlab.ui.control.Label
        ExposuresAddedShowStepIndexesCheckBox  matlab.ui.control.CheckBox
        ExposuresAddedTableRemoveButton  matlab.ui.control.Button
        ExposuresAddedTable             matlab.ui.control.Table
        ExposureOptionsPanel            matlab.ui.container.Panel
        ExposureOptionsPanelGridLayout  matlab.ui.container.GridLayout
        ExposureOptionsStartDateDatePicker  matlab.ui.control.DatePicker
        StartDateDatePickerLabel_2      matlab.ui.control.Label
        ExposureOptionsUTCOffsetEditField  matlab.ui.control.EditField
        UTCLabel                        matlab.ui.control.Label
        ExposureOptionsTargetNameEditField  matlab.ui.control.EditField
        TargetNameEditFieldLabel        matlab.ui.control.Label
        ExposureOptionsStartTimeEditField  matlab.ui.control.EditField
        StartTimeEditField_3Label       matlab.ui.control.Label
        ExposureOptionsAddExposureButton  matlab.ui.control.Button
        ExposuresAvailableWindowsTablePanel  matlab.ui.container.Panel
        ExposuresAvailableWindowsTablePanelGridLayout  matlab.ui.container.GridLayout
        ExposuresAvailableIgnoreWindowsUnitsDropDown  matlab.ui.control.DropDown
        ExposuresAvailableIgnoreWindowsEditField  matlab.ui.control.NumericEditField
        ExposuresAvailableIgnoreWindowsCheckBox  matlab.ui.control.CheckBox
        ExposuresAvailableWindowsTable  matlab.ui.control.Table
        ConflictResolutionTab           matlab.ui.container.Tab
        ConflictResolutionTabGridLayout  matlab.ui.container.GridLayout
        ConflictsPanel                  matlab.ui.container.Panel
        ConflictResolutionUnderConstructionPanelGridLayout  matlab.ui.container.GridLayout
        ConflictTableUTCOffsetEditField  matlab.ui.control.EditField
        UTCOffsetLabel_5                matlab.ui.control.Label
        ConflictTableTimestampFormatDropDown  matlab.ui.control.DropDown
        TimestampDropDownLabel_5        matlab.ui.control.Label
        ConflictTableShowStepIndexesCheckBox  matlab.ui.control.CheckBox
        ConflictTableDeleteEventButton  matlab.ui.control.Button
        ConflictTable                   matlab.ui.control.Table
        FinalizePlanTab                 matlab.ui.container.Tab
        FinalizePlanTabGridLayout       matlab.ui.container.GridLayout
        FinalizePlanUIHorizontalSlider  matlab.ui.control.Slider
        FinalizePlanUIVerticalSlider    matlab.ui.control.Slider
        FinalizePlanViewOptionsPanel    matlab.ui.container.Panel
        FinalizePlanTablePanel          matlab.ui.container.Panel
        FinalizePlanTablePanelGridLayout  matlab.ui.container.GridLayout
        AddChargingEventsCheckBox       matlab.ui.control.CheckBox
        FinalizePlanShowStepIndexesCheckBox  matlab.ui.control.CheckBox
        FinalizePlanUTCOffsetEditField  matlab.ui.control.EditField
        UTCOffsetEditField_2Label_2     matlab.ui.control.Label
        FinalizePlanShowUnallocatedTimesCheckBox  matlab.ui.control.CheckBox
        FinalizePlanTimestampFormatDropDown  matlab.ui.control.DropDown
        TimestampDropDownLabel_6        matlab.ui.control.Label
        FinalizePlanDisplayplanasDropDown  matlab.ui.control.DropDown
        DisplayplanasDropDownLabel      matlab.ui.control.Label
        FinalizePlanUITable             matlab.ui.control.Table
        FinalizePlanRemoveButton        matlab.ui.control.Button
        FinalizePlanPlotPanel           matlab.ui.container.Panel
        ExportJSONTab                   matlab.ui.container.Tab
        ExportJSONTabGridLayout         matlab.ui.container.GridLayout
        ExportJSONUIVerticalSlider      matlab.ui.control.Slider
        ExportJSONPreviewPanel          matlab.ui.container.Panel
        ExportJSONPreviewGridLayout     matlab.ui.container.GridLayout
        ExportJSONCommandDescriptionTextArea  matlab.ui.control.TextArea
        StoredCommandDescriptionoptionalTextAreaLabel  matlab.ui.control.Label
        ExportJSONPreview               matlab.ui.control.TextArea
        ExportJSONCommandCommentsTextArea  matlab.ui.control.TextArea
        StoredCommandCommentsoptionalLabel  matlab.ui.control.Label
        ExportJSONPanel                 matlab.ui.container.Panel
        ExportJSONPanelGridLayout       matlab.ui.container.GridLayout
        ExportJSONButton                matlab.ui.control.Button
        ExportJSONPlanCommentsoptionalTextArea  matlab.ui.control.TextArea
        ObservationPlanCommentsoptionalTextAreaLabel  matlab.ui.control.Label
        ExportJSONAuthorsEditField      matlab.ui.control.EditField
        AuthorsEditFieldLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        tleHandler
        satelliteScenarioHandler
        tabStateHandler
    end

    properties (Access = private)

    end
    
    methods (Access = private)
        
        % TODO: clean up this structure a bit
        function update_all_tab_state_indicators(app)
            update_TLE_tab_status_label(app);
            update_SGP4_tab_status_label(app);
            update_ground_stations_tab_status_label(app);
            update_targets_tab_status_label(app);
            update_exposures_tab_status_label(app);
        end
        
        function update_state_indicator(app, label_to_update, new_state)

            new_background_color = new_state.Color;
            label_to_update.BackgroundColor = new_background_color;
            
        end
        
        function update_TLE_tab_status_label(app)
            update_state_indicator(app, app.SatelliteTabStatusLabel, app.tabStateHandler.SatelliteTabState)
        end
        
        function update_SGP4_tab_status_label(app)
            update_state_indicator(app, app.SGP4TabStatusLabel, app.tabStateHandler.PropagationTabState)
        end
        
        function update_ground_stations_tab_status_label(app)
            update_state_indicator(app, app.GroundStationsTabStatusLabel, app.tabStateHandler.AccessTabState)
        end
        
        function update_targets_tab_status_label(app)
            update_state_indicator(app, app.TargetsTabStatusLabel, app.tabStateHandler.TargetTabState)
        end
        
        function update_exposures_tab_status_label(app)
            update_state_indicator(app, app.ExposuresTabStatusLabel, app.tabStateHandler.ExposureTabState)
        end
        
        function orb_prop(app)

            % Broadcast tab state event
            % TODO: more robust version of this
            app.tabStateHandler.sendTransitionEvent('PROPAGATION_CHANGED')

            % Quickly write TLE file (hacky)
            file_name = 'test_tle.tle';
            app.tleHandler.saveTLEToFile('CUTE', file_name, true, 'UTF-8', false);
            
            % Build date vec from date and time picker
            UTC_offset_str = app.SGP4PropagatorUTCOffsetEditField.Value;
            SGP4_expected_timezone = 'UTC';  % don't change this!  
                    
            start_datetime = app.SGP4PropagatorStartDateDatePicker.Value;
            start_time_only = datetime(app.SGP4PropagatorStartTimeEditField.Value);
            start_datetime.Hour = start_time_only.Hour;
            start_datetime.Minute = start_time_only.Minute;
            start_datetime.Second = start_time_only.Second;
            start_datetime.TimeZone = UTC_offset_str;
            start_datetime.TimeZone = SGP4_expected_timezone;  % converting to UTC
            
            end_datetime = app.SGP4PropagatorEndDateDatePicker.Value;
            end_time_only = datetime(app.SGP4PropagatorEndTimeEditField.Value);
            end_datetime.Hour = end_time_only.Hour;
            end_datetime.Minute = end_time_only.Minute;
            end_datetime.Second = end_time_only.Second;
            end_datetime.TimeZone = UTC_offset_str;
            end_datetime.TimeZone = SGP4_expected_timezone;  % converting to UTC

            % Convert timestep value into minutes
            delta_units = app.SGP4PropagatorTimestepUnitsDropDown.Value;
            delta_value = app.SGP4PropagatorTimestepEditField.Value;
            if strcmp(delta_units,'milliseconds')
                delta_sec = delta_value/(1000*60);
            elseif strcmp(delta_units, 'seconds')
                delta_sec = delta_value;
            elseif strcmp(delta_units, 'minutes')
                delta_sec = delta_value*60;
            end

            % Set time constraints on satellite scenario
            app.satelliteScenarioHandler.ScenarioStartDatetime = start_datetime;
            app.satelliteScenarioHandler.ScenarioEndDatetime = end_datetime;
            app.satelliteScenarioHandler.ScenarioTimestepSizeSec = delta_sec;

            % Add satellite from TLE to satellite scenario
            % TODO: move this somewhere else?
            app.satelliteScenarioHandler.addSatelliteFromTLEFile(file_name);

            % Open graphic
            app.satelliteScenarioHandler.displayGraphics();

            % Broadcast tab state event
            % TODO: more robust version of this
            app.tabStateHandler.sendTransitionEvent('PROPAGATION_CALCULATED')
            app.update_all_tab_state_indicators();
            

%             [LLA, times, sat_scenario_obj, sat_obj] = satellite_scenario_helper(start_datetime, end_datetime, delta, default_tle_filename);
% 
%             app.deltamin = delta*60;
%             app.LLA_all = LLA';
%             app.SGP4_times = times';
%             app.sat_scenario = sat_scenario_obj;
%             app.satellite_object = sat_obj;
            
%             % Create satellite scenario
%             sat_scen = satelliteScenario(start_datetime, end_datetime, app.deltamin);
%             app.sat_scenario = sat_scen;
%             
%             % Add satellite to scenario
%             sat_obj = satellite(sat_scen, default_tle_filename);
%             app.satellite_object = sat_obj;
% 
%             % Get resulting LLA
%             [LLA_2, ~, times_2] = states(app.satellite_object, 'CoordinateFrame', 'geographic');
%             app.LLA_all = LLA;
%             app.SGP4_times = times;

%             % Convert times to MJD for other functions (probably not needed
%             % later)
%             app.mjd_all = mjuliandate(app.SGP4_times);
% 
%             % Calculate SAA exclusion zones
%             calculate_SAA_exclusion_zones(app);
%             
%             % Calculate polar keepout zones
%             calculate_polar_keepout_zones(app);
%             
%             % Calculate night times (in eclipse with Earth)
%             calculate_eclipse_times(app);
%               
%             % Display LLA and times in main table
%             update_LLA_table(app);
%             
%             %Write ground track to KML file
%             LLA_with_times = [app.mjd_all, app.LLA_all];
%             KMLplot(app, LLA_with_times);
%             
%             % Update masterplan
%             update_masterplan(app);
%             
%             % Update GUI state
%             set_GUI_state_to_waiting_for_passovers(app);
            
        end
        
        function populateTLE(app, TLE)
            TLE_list = app.tleHandler.TLEHandles;

            if ~isempty(TLE_list)
                % Load all the variables from the TLE
                % Line 1
                app.SatelliteInfoNameEditField.Value = TLE.SatelliteName;
                app.SatelliteCatalogNumberEditField.Value = num2str(TLE.CatalogNumber);
                app.ClassificationEditField.Value = num2str(TLE.Classification);
                app.IntlDesignatorlaunchyrEditField.Value = num2str(TLE.LaunchYearDesignator);
                app.IntlDesignatorlaunchnoEditField.Value  = num2str(TLE.LaunchNumberDesignator);
                app.IntlDesignatorpieceEditField.Value = num2str(TLE.LaunchPieceDesignator);
                app.EpochYearEditField.Value = num2str(TLE.EpochYear);
                app.EpochDayEditField.Value  = num2str(TLE.EpochDay);
                app.FirstDerivofMeanMotionEditField.Value = TLE.FirstDerivofMeanMotion;
                app.SecondDerivofMeanMotionEditField.Value = TLE.SecondDerivofMeanMotion;
                app.RadiationPressureCoefficientEditField.Value  = TLE.BStar;
                app.EphemerisTypeEditField.Value = num2str(TLE.EphemerisType);
                app.ElementSetNumberEditField.Value = num2str(TLE.ElementSetNum);
                app.Checksummodulo10EditField.Value = num2str(TLE.ChecksumOne);

                % Line 2
                app.SatelliteCatalogNumberEditField_2.Value = num2str(TLE.CatalogNumber);
                app.InclinationdegEditField.Value = num2str(TLE.Inclination);
                app.RAANdegEditField.Value  = num2str(TLE.RAAN);
                app.EccentricityEditField.Value = num2str(TLE.Eccentricity);
                app.ArgumentofPerigeedegEditField.Value = num2str(TLE.ArgumentOfPeriapsis);
                app.MeanAnomalydegEditField.Value  = num2str(TLE.MeanAnomaly);
                app.MeanMotionrevdayEditField.Value = num2str(TLE.MeanOrbitalPeriod);
                app.RevolutionnoatEpochEditField.Value  = num2str(TLE.RevolutionNoAtEpoch);
                app.Checksummodulo10EditField_2.Value = num2str(TLE.ChecksumTwo);

            else
                % Empty everything
                % Line 1
                app.SatelliteInfoNameEditField.Value = '';
                app.SatelliteCatalogNumberEditField.Value = '';
                app.ClassificationEditField.Value = '';
                app.IntlDesignatorlaunchyrEditField.Value = '';
                app.IntlDesignatorlaunchnoEditField.Value  = '';
                app.IntlDesignatorpieceEditField.Value = '';
                app.EpochYearEditField.Value = '';
                app.EpochDayEditField.Value  = '';
                app.FirstDerivofMeanMotionEditField.Value = '';
                app.SecondDerivofMeanMotionEditField.Value = '';
                app.RadiationPressureCoefficientEditField.Value = '';
                app.EphemerisTypeEditField.Value = '';
                app.ElementSetNumberEditField.Value = '';
                app.Checksummodulo10EditField.Value = '';

                % Line 2
                app.SatelliteCatalogNumberEditField_2.Value = '';
                app.InclinationdegEditField.Value = '';
                app.RAANdegEditField.Value  = '';
                app.EccentricityEditField.Value = '';
                app.ArgumentofPerigeedegEditField.Value = '';
                app.MeanAnomalydegEditField.Value  = '';
                app.MeanMotionrevdayEditField.Value = '';
                app.RevolutionnoatEpochEditField.Value  = '';
                app.Checksummodulo10EditField_2.Value = '';
            end
        end
        
        function populateLoadedSatellitesTable(app)
            % Load TLEHandles
            TLE_list = app.tleHandler.TLEHandles;

            new_table = string();

            % Load the data
            for i = 1:numel(TLE_list)

                % Load TLE object
                TLE = TLE_list(i);

                % Create new table
                new_table(i, 1) = TLE.SatelliteName;
                new_table(i, 2) = num2str(TLE.Inclination);
                new_table(i, 3) = num2str(TLE.RAAN);
                new_table(i, 4) = num2str(TLE.Eccentricity);
                new_table(i, 5) = num2str(TLE.ArgumentOfPeriapsis);
                new_table(i, 6) = num2str(TLE.MeanOrbitalPeriod);
            end

            % Load newly created table and update the GUI
            app.LoadedSatellitesTable.Data = new_table;
        end

        function updateLoadedSatellitesTable(app)
            app.LoadedSatellitesTable.Data = [];

            % Load TLEHandles
            TLE_list = app.tleHandler.TLEHandles;

            if ~isempty(TLE_list)

                new_table = string();

                % Load the data
                for i = 1:numel(TLE_list)

                    % Load TLE object
                    TLE = TLE_list(i);

                    % Create new table
                    new_table(i, 1) = TLE.SatelliteName;
                    new_table(i, 2) = num2str(TLE.Inclination);
                    new_table(i, 3) = num2str(TLE.RAAN);
                    new_table(i, 4) = num2str(TLE.Eccentricity);
                    new_table(i, 5) = num2str(TLE.ArgumentOfPeriapsis);
                    new_table(i, 6) = num2str(TLE.MeanOrbitalPeriod);
                end

                % Load newly created table and update the GUI
                app.LoadedSatellitesTable.Data = new_table;

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Initialize handler objects
            app.tleHandler = TLEHandler();
            app.satelliteScenarioHandler = SatelliteScenarioHandler();
            app.tabStateHandler = TabStateHandler();


            % Broadcast initialization is complete
            % TODO: handle event strings as app properties
            app.tabStateHandler.sendTransitionEvent('GUI_INITIALIZATION_COMPLETE');
            app.update_all_tab_state_indicators();

        end

        % Button pushed function: SGP4PropagatorPropagateButton
        function SGP4PropagatorPropagateButtonPushed(app, event)
            % March 2023: experimenting with using satelliteScenario and
            % satellite objects built into the Aerospace toolbox
            orb_prop(app);

            % If successful, allow user to calculate passovers
            app.PassoverOptionsCalculatePassoversButton.Enable = true;
            app.TargetSchedulerCalculateVisibilityButton.Enable = true;
            
%             % Update groundtrack plot to match start/end time inputted to
%             % SGP4 (effectively plotting all data)
%             app.SGP4GroundtrackPlotPlotfromDatePicker.Value = app.SGP4PropagatorStartDateDatePicker.Value;
%             app.SGP4GroundtrackPlotPlottoDatePicker.Value = app.SGP4PropagatorEndDateDatePicker.Value;
%             app.SGP4GroundtrackPlotStartTimeEditField.Value = app.SGP4PropagatorStartTimeEditField.Value;
%             app.SGP4GroundtrackPlotEndTimeEditField.Value = app.SGP4PropagatorEndTimeEditField.Value;
%             update_SGP4_groundtrack_plot(app);
%             % Update masterplan plot to match start/end time inputted to
%             % SGP4 (effectively plotting all data)
%             app.FinalizePlanPlotPlotfromDatePicker.Value = app.SGP4PropagatorStartDateDatePicker.Value;
%             app.FinalizePlanPlotPlottoDatePicker.Value = app.SGP4PropagatorEndDateDatePicker.Value;
%             app.FinalizePlanPlotStartTimeEditField.Value = app.SGP4PropagatorStartTimeEditField.Value;
%             app.FinalizePlanPlotEndTimeEditField.Value = app.SGP4PropagatorEndTimeEditField.Value;
%             update_masterplan_groundtrack_plot(app);
            
            % Match given UTC offset in LLA table
            app.SGP4TableUTCOffsetEditField.Value = app.SGP4PropagatorUTCOffsetEditField.Value;
        end

        % Button pushed function: TLEFromFileOpenButton
        function TLEFromFileOpenButtonPushed(app, event)
            % Prompt user for file
            [file, path] = uigetfile( ...
                {'*.tle';'*.txt';'*.*'}, 'Pick a TLE file');
            if isequal(file,0)
                % TODO: Sebastian fixed an error here, I believe?
                disp('User selected Cancel');
            else
                if ~isempty(file)

                    % Successfully selected a file
                    TLE_source = fullfile(path,file);
                    fprintf('User selected %s\n', TLE_source);
                    app.TLEFileEditField.Value = TLE_source;

                    % Load TLE from file via TLE handler
                    ignore_checksum = app.SatelliteInfoIgnoreChecksumsCheckBox.Value;

                    % Add objects, allows for multiple TLEs to be added. They must
                    % be separated by commas
                    search_TLE_name = app.TLEFromFileSearchTextArea.Value;

                    % If the search is not empty, process the input
                    if isequal(1, numel(search_TLE_name))
                        search_TLE_name = strtrim(split(cell2mat(search_TLE_name), ','));
                    end

                    % If search is empty, load all of them
                    if isempty(search_TLE_name)
                        TLEs_to_load = '';
                        app.tleHandler.addTLEFromFile(TLE_source, ignore_checksum, TLEs_to_load);
                    else
                        for i = 1:numel(search_TLE_name)
                            TLEs_to_load = search_TLE_name{i};
                                app.tleHandler.addTLEFromFile(TLE_source, ignore_checksum, TLEs_to_load);
                        end
                    end

                    % Populate the TLE tab wiht the first TLE
                    TLE_list = app.tleHandler.TLEHandles;

                    if ~isempty(TLE_list)
                        TLE_to_show = TLE_list(1);
                        populateTLE(app, TLE_to_show);
                        populateLoadedSatellitesTable(app)
                    end

                    % Broadcast to the tab state manager that the TLE info has been
                    % changed.
                    % TODO: call other tabs' reset functions to clear our old info
                    % TODO: create a property variable to handle event names
                    app.tabStateHandler.sendTransitionEvent('SATELLITE_DEFINITION_CHANGED');
                    % TODO: more robust version of this
                    app.SGP4PropagatorPropagateButton.Enable = false;

                    % Check whether TLE(s) are valid
                    % TODO: support multiple TLEs
                    %                 tle_format_is_valid = app.tleHandler.validateAllTLEFormats();
                    %                 tle_checksum_is_valid = app.tleHandler.validateAllTLEChecksums();
                    if ~isempty(app.tleHandler.TLEHandles)%(tle_format_is_valid && tle_checksum_is_valid)

                        % If valid, broadcast state event
                        app.tabStateHandler.sendTransitionEvent('SATELLITE_DEFINITION_VALID');
                        % TODO: more robust version of this
                        app.SGP4PropagatorPropagateButton.Enable = true;

                    end

                    % TODO: more generic event listener in TabStateHandler?
                    app.update_all_tab_state_indicators();
                else
                    print('empty')
                end
            end
        end

        % Button pushed function: LoadedSatellitesDeleteButton
        function LoadedSatellitesDeleteButtonPushed(app, event)
            % FIXED, NOW IMPLEMENT THIS FUNCTION INTO THE TLE FACTORY/TLE
            % HANDLER

            % Allow for multiple deletions at the same time
            selected_TLEs = app.LoadedSatellitesTable.Selection;
            num_selected_TLEs = length(selected_TLEs);

            j = 1;

            for i = 1:num_selected_TLEs

                % Get data in table
                current_table_data = app.LoadedSatellitesTable.Data;

                % If there's multiple deletions, decrease index by one
                if isequal(i, 1)
                    TLE_index = selected_TLEs(i);
                else
                    TLE_index = selected_TLEs(i) - j;
                    j = j + 1;
                end

                % Get names column
                names_column = 1;
                
                % Find name in table
                TLE_name = current_table_data{TLE_index, names_column};

                % Find TLE object
                TLE = app.tleHandler.getTLEByName(TLE_name, false);
                
                % Remove TLE from TLE list
                app.tleHandler.removeTLE(TLE, TLE_index);

                % Update tab
                TLE_list = app.tleHandler.TLEHandles;

                if ~isempty(TLE_list)
                    first_TLE = TLE_list(1);
                    populateTLE(app, first_TLE);
                else
                    populateTLE(app, 0);
                end

                % Update table
                updateLoadedSatellitesTable(app);

                % Broadcast to the tab state manager that the TLE info has been
                % changed. 
                % TODO: call other tabs' reset functions to clear our old info
                % TODO: create a property variable to handle event names
                app.tabStateHandler.sendTransitionEvent('SATELLITE_DEFINITION_CHANGED');
                % TODO: more robust version of this
                app.SGP4PropagatorPropagateButton.Enable = false;

                % Check whether TLE(s) are valid
                % TODO: support multiple TLEs
%                 tle_format_is_valid = app.tleHandler.validateAllTLEFormats();
%                 tle_checksum_is_valid = app.tleHandler.validateAllTLEChecksums();
                if ~isempty(app.tleHandler.TLEHandles)%(tle_format_is_valid && tle_checksum_is_valid)

                    % If valid, broadcast state event
                    app.tabStateHandler.sendTransitionEvent('SATELLITE_DEFINITION_VALID');
                    % TODO: more robust version of this
                    app.SGP4PropagatorPropagateButton.Enable = true;

                end

                % TODO: more generic event listener in TabStateHandler?
                app.update_all_tab_state_indicators();
                
            end
        end

        % Button pushed function: TLEFromURLLoadButton
        function TLEFromURLLoadButtonPushed(app, event)
                       
            % Load data from inputted URL
            TLE_source = app.TLEFromURLEditField.Value;  % default use https://www.celestrak.com/NORAD/elements/active.txt
            fprintf('User selected %s\n', TLE_source);

            % Read off ignore checksum value
            ignore_checksum = app.SatelliteInfoIgnoreChecksumsCheckBox.Value;

            % Add objects, allows for multiple TLEs to be added. They must
            % be separated by commas
            sat_num_or_name = app.TLEFromURLSearchTextArea.Value;

            % If search is empty, error
            if isempty(sat_num_or_name)
                warning("A satellite number or name must be given.")
                return
            else

                % Split lines 
                if isequal(1, numel(sat_num_or_name))
                    sat_num_or_name = strtrim(split(cell2mat(sat_num_or_name), ','));
                end

                for i = 1:numel(sat_num_or_name)
                    app.tleHandler.addTLEFromURL(TLE_source, ignore_checksum, sat_num_or_name{i});
                end
            end

            % Populate the TLE tab wiht the first TLE and load the table
            TLE_list = app.tleHandler.TLEHandles;

            if ~isempty(TLE_list)
                TLE_to_show = TLE_list(1);
                populateTLE(app, TLE_to_show);
                populateLoadedSatellitesTable(app)
            end


                % Broadcast to the tab state manager that the TLE info has been
                % changed. 
                % TODO: call other tabs' reset functions to clear our old info
                % TODO: create a property variable to handle event names
                app.tabStateHandler.sendTransitionEvent('SATELLITE_DEFINITION_CHANGED');
                % TODO: more robust version of this
                app.SGP4PropagatorPropagateButton.Enable = false;

                % Check whether TLE(s) are valid
                % TODO: support multiple TLEs
%                 tle_format_is_valid = app.tleHandler.validateAllTLEFormats();
%                 tle_checksum_is_valid = app.tleHandler.validateAllTLEChecksums();
                if ~isempty(app.tleHandler.TLEHandles)%(tle_format_is_valid && tle_checksum_is_valid)

                    % If valid, broadcast state event
                    app.tabStateHandler.sendTransitionEvent('SATELLITE_DEFINITION_VALID');
                    % TODO: more robust version of this
                    app.SGP4PropagatorPropagateButton.Enable = true;

                end

                % TODO: more generic event listener in TabStateHandler?
                app.update_all_tab_state_indicators();
            
        end

        % Cell selection callback: LoadedSatellitesTable
        function LoadedSatellitesTableCellSelection(app, event)
            
            % Get the index of the selected events
            index = event.Indices;

            % Extract the row index
            if ~isempty(index)
                row_selected = index(1);
                names_column = 1;

                % Get TLE from table
                TLE_to_show = app.LoadedSatellitesTable.Data(row_selected, names_column);

                % Find TLE in TLE list
                TLE = app.tleHandler.TLEHandles(row_selected);
                sat_name = TLE.SatelliteName;

                % If name and TLE match,
                if strcmp(sat_name, TLE_to_show)
                    populateTLE(app, TLE)
                else
                    warning('CATASTROPHIC ERROR, GO TO A BUNKER RIGHT NOW')
                end
            end
        end

        % Value changed function: SatelliteInfoNameEditField
        function SatelliteInfoNameEditFieldValueChanged(app, event)
            % Get new name from GUI
            new_name_input = app.SatelliteInfoNameEditField.Value;
            
            % Get the selected TLE to change the name of from the table
            selected_TLEs = app.LoadedSatellitesTable.Selection;

            % Change the TLE that's selected
            if ~isempty(selected_TLEs)
                % Get the TLE object
                new_name_TLE = app.tleHandler.TLEHandles(selected_TLEs);

                % Change the name
                new_name_TLE.SatelliteName = new_name_input;

                % Store the changes
                app.tleHandler.TLEHandles(selected_TLEs) = new_name_TLE;

                % Update the tab
                populateTLE(app, new_name_TLE);

                % Update the table
                updateLoadedSatellitesTable(app);           
            else
                warning("You must select a TLE from the table before changing its name.")
            end
        end

        % Button pushed function: LoadedSatellitesLoadButton
        function LoadedSatellitesLoadButtonPushed(app, event)
            app.TLEFromFileOpenButton.ButtonPushedFcn(app, event);
        end

        % Button pushed function: LoadedSatellitesExportButton
        function LoadedSatellitesExportButtonPushed(app, event)
            % TODO: Export the lines instead of the data in the table


            % Get TLE list
            TLE_list = app.tleHandler.TLEHandles;

            % Name of file is the name of the first satellite
            if ~isempty(TLE_list)
                default_filename = TLE_list(1).SatelliteName;
            else
                default_filename = 'TLE file';
            end

            % Prompt user for file
            window_title = 'Save as';
            [file, path] = uiputfile( ...
                {'*.txt', 'TXT Files (*.txt)'; '*.xlsx', 'Excel Files (*.xlsx)'}, ...
                window_title, default_filename);

            % Check if user selected cancel
            if isequal(file,0)
                disp('User selected Cancel, did not save.');
            else

                % Successfully selected a file
                table_data = string();

                k = 1;
                for i = 1:numel(TLE_list)
                    table_data(k, 1) = TLE_list(i).SatelliteName;
                    table_data(k + 1, 1) = TLE_list(i).TLELine1;
                    table_data(k + 2, 1) = TLE_list(i).TLELine2;
                    k = k + 3;
                end

                table_dest = fullfile(path,file);
                fprintf('User selected %s to save file at.\n', table_dest);

                % Write file
                % table_data = app.LoadedSatellitesTable.Data;
                writematrix(table_data, table_dest)
            end
        end

        % Button pushed function: SatellitePresetAddButton
        function SatellitePresetAddButtonPushed(app, event)
            option = app.SatellitePresetDropDown.Value;

            if isequal(option, 'Sun-synchronous')
                % Assign name
                sat_name = 'Sun-sync Orbit Preset';

                % Assign Semi Major axis
                semi_major_axis = 700;

                % Assign Eccentricity
                eccentricity = 0.002;

                % Assign Inclination
                inclination = 98;

                % Assign RAAN
                RAAN = 180;

                % Assign Argument of Periapsis
                arg_of_periapsis = 180;

                % Assign Mean Anomaly
                mean_motion = 10;

                % Create TLE
                app.tleHandler.addTLEfromKeplerianElements(sat_name, semi_major_axis, ...
                     eccentricity, inclination, RAAN, arg_of_periapsis, mean_motion);

                % Update tab
                sun_sync_TLE = app.tleHandler.getTLEByName(sat_name, false);
                
                populateTLE(app, sun_sync_TLE);
                
                % Update table
                updateLoadedSatellitesTable(app);

            elseif isequal(option, 'Geostationary')
                % Assign name
                sat_name = 'Goestationary Orbit Preset';

                % Assign Semi Major axis
                semi_major_axis = 40000;

                % Assign Eccentricity
                eccentricity = 0.001;

                % Assign Inclination
                inclination = 0;

                % Assign RAAN
                RAAN = 0;

                % Assign Argument of Periapsis
                arg_of_periapsis = 0;

                % Assign Mean Anomaly
                mean_motion = 5;

                % Create TLE
                app.tleHandler.addTLEfromKeplerianElements(sat_name, semi_major_axis, ...
                     eccentricity, inclination, RAAN, arg_of_periapsis, mean_motion);

                % Update tab
                sun_sync_TLE = app.tleHandler.getTLEByName(sat_name, false);
                
                populateTLE(app, sun_sync_TLE);
                
                % Update table
                updateLoadedSatellitesTable(app);

            end
        end

        % Value changed function: 
        % SatelliteInfoAutogenerateChecksumsCheckBox
        function SatelliteInfoAutogenerateChecksumsCheckBoxValueChanged(app, event)
            % value = app.SatelliteInfoAutogenerateChecksumsCheckBox.Value;
            % TODO: Implement this
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MasterGridLayout = uigridlayout(app.UIFigure);
            app.MasterTabGroup = uitabgroup(app.MasterGridLayout);
            app.SatelliteTab = uitab(app.MasterTabGroup);
            app.SatelliteTab.Title = 'Satellite';
            app.SatelliteTabLayout = uigridlayout(app.SatelliteTab);
            app.SatelliteInfoPanel = uipanel(app.SatelliteTabLayout);
            app.SatelliteInfoPanel.Title = 'Satellite Info';
            app.SatelliteInfoPanelLayout = uigridlayout(app.SatelliteInfoPanel);
            app.TLELine1Panel = uipanel(app.SatelliteInfoPanelLayout);
            app.TLELine1Panel.Title = 'TLE Line 1';
            app.TLELine1Layout = uigridlayout(app.TLELine1Panel);
            app.SatelliteCatalogNumberEditFieldLabel = uilabel(app.TLELine1Layout);
            app.SatelliteCatalogNumberEditFieldLabel.Text = 'Satellite Catalog Number';
            app.SatelliteCatalogNumberEditField = uieditfield(app.TLELine1Layout, 'text');
            app.SatelliteCatalogNumberEditField.Editable = 'off';
            app.ClassificationEditFieldLabel = uilabel(app.TLELine1Layout);
            app.ClassificationEditFieldLabel.Text = 'Classification';
            app.ClassificationEditField = uieditfield(app.TLELine1Layout, 'text');
            app.ClassificationEditField.Editable = 'off';
            app.IntlDesignatorlaunchyrEditFieldLabel = uilabel(app.TLELine1Layout);
            app.IntlDesignatorlaunchyrEditFieldLabel.Text = 'Intl. Designator (launch yr)';
            app.IntlDesignatorlaunchyrEditField = uieditfield(app.TLELine1Layout, 'text');
            app.IntlDesignatorlaunchyrEditField.Editable = 'off';
            app.IntlDesignatorlaunchnoEditFieldLabel = uilabel(app.TLELine1Layout);
            app.IntlDesignatorlaunchnoEditFieldLabel.Text = 'Intl. Designator (launch no.)';
            app.IntlDesignatorlaunchnoEditField = uieditfield(app.TLELine1Layout, 'text');
            app.IntlDesignatorlaunchnoEditField.Editable = 'off';
            app.IntlDesignatorpieceEditFieldLabel = uilabel(app.TLELine1Layout);
            app.IntlDesignatorpieceEditFieldLabel.Text = 'Intl Designator (piece)';
            app.IntlDesignatorpieceEditField = uieditfield(app.TLELine1Layout, 'text');
            app.IntlDesignatorpieceEditField.Editable = 'off';
            app.EpochYearEditFieldLabel = uilabel(app.TLELine1Layout);
            app.EpochYearEditFieldLabel.Text = 'Epoch Year';
            app.EpochYearEditField = uieditfield(app.TLELine1Layout, 'text');
            app.EpochYearEditField.Editable = 'off';
            app.EpochDayEditFieldLabel = uilabel(app.TLELine1Layout);
            app.EpochDayEditFieldLabel.Text = 'Epoch Day ';
            app.EpochDayEditField = uieditfield(app.TLELine1Layout, 'text');
            app.EpochDayEditField.Editable = 'off';
            app.FirstDerivofMeanMotionEditFieldLabel = uilabel(app.TLELine1Layout);
            app.FirstDerivofMeanMotionEditFieldLabel.Text = 'First Deriv. of Mean Motion';
            app.FirstDerivofMeanMotionEditField = uieditfield(app.TLELine1Layout, 'text');
            app.FirstDerivofMeanMotionEditField.Editable = 'off';
            app.SecondDerivofMeanMotionEditFieldLabel = uilabel(app.TLELine1Layout);
            app.SecondDerivofMeanMotionEditFieldLabel.Text = 'Second Deriv. of Mean-Motion';
            app.SecondDerivofMeanMotionEditField = uieditfield(app.TLELine1Layout, 'text');
            app.SecondDerivofMeanMotionEditField.Editable = 'off';
            app.RadiationPressureCoefficientEditFieldLabel = uilabel(app.TLELine1Layout);
            app.RadiationPressureCoefficientEditFieldLabel.Text = 'Radiation Pressure Coefficient';
            app.RadiationPressureCoefficientEditField = uieditfield(app.TLELine1Layout, 'text');
            app.RadiationPressureCoefficientEditField.Editable = 'off';
            app.EphemerisTypeEditFieldLabel = uilabel(app.TLELine1Layout);
            app.EphemerisTypeEditFieldLabel.Text = 'Ephemeris Type';
            app.EphemerisTypeEditField = uieditfield(app.TLELine1Layout, 'text');
            app.EphemerisTypeEditField.Editable = 'off';
            app.ElementSetNumberEditFieldLabel = uilabel(app.TLELine1Layout);
            app.ElementSetNumberEditFieldLabel.Text = 'Element Set Number';
            app.ElementSetNumberEditField = uieditfield(app.TLELine1Layout, 'text');
            app.ElementSetNumberEditField.Editable = 'off';
            app.Checksummodulo10EditFieldLabel = uilabel(app.TLELine1Layout);
            app.Checksummodulo10EditFieldLabel.Text = 'Checksum (modulo 10)';
            app.Checksummodulo10EditField = uieditfield(app.TLELine1Layout, 'text');
            app.Checksummodulo10EditField.Editable = 'off';
            app.TLELine2Panel = uipanel(app.SatelliteInfoPanelLayout);
            app.TLELine2Panel.Title = 'TLE Line 2';
            app.TLELine2Layout = uigridlayout(app.TLELine2Panel);
            app.SatelliteCatalogNumberEditField_2Label = uilabel(app.TLELine2Layout);
            app.SatelliteCatalogNumberEditField_2Label.Text = 'Satellite Catalog Number';
            app.SatelliteCatalogNumberEditField_2 = uieditfield(app.TLELine2Layout, 'text');
            app.SatelliteCatalogNumberEditField_2.Editable = 'off';
            app.InclinationdegEditFieldLabel = uilabel(app.TLELine2Layout);
            app.InclinationdegEditFieldLabel.Text = 'Inclination (deg)';
            app.InclinationdegEditField = uieditfield(app.TLELine2Layout, 'text');
            app.InclinationdegEditField.Editable = 'off';
            app.RAANdegEditFieldLabel = uilabel(app.TLELine2Layout);
            app.RAANdegEditFieldLabel.Text = 'RAAN (deg)';
            app.RAANdegEditField = uieditfield(app.TLELine2Layout, 'text');
            app.RAANdegEditField.Editable = 'off';
            app.EccentricityEditFieldLabel = uilabel(app.TLELine2Layout);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.TLELine2Layout, 'text');
            app.EccentricityEditField.Editable = 'off';
            app.ArgumentofPerigeedegEditFieldLabel = uilabel(app.TLELine2Layout);
            app.ArgumentofPerigeedegEditFieldLabel.Text = 'Argument of Perigee (deg)';
            app.ArgumentofPerigeedegEditField = uieditfield(app.TLELine2Layout, 'text');
            app.ArgumentofPerigeedegEditField.Editable = 'off';
            app.MeanAnomalydegEditFieldLabel = uilabel(app.TLELine2Layout);
            app.MeanAnomalydegEditFieldLabel.Text = 'Mean Anomaly (deg)';
            app.MeanAnomalydegEditField = uieditfield(app.TLELine2Layout, 'text');
            app.MeanAnomalydegEditField.Editable = 'off';
            app.MeanMotionrevdayEditFieldLabel = uilabel(app.TLELine2Layout);
            app.MeanMotionrevdayEditFieldLabel.Text = 'Mean Motion (rev/day)';
            app.MeanMotionrevdayEditField = uieditfield(app.TLELine2Layout, 'text');
            app.MeanMotionrevdayEditField.Editable = 'off';
            app.RevolutionnoatEpochEditFieldLabel = uilabel(app.TLELine2Layout);
            app.RevolutionnoatEpochEditFieldLabel.Text = 'Revolution no. at Epoch';
            app.RevolutionnoatEpochEditField = uieditfield(app.TLELine2Layout, 'text');
            app.RevolutionnoatEpochEditField.Editable = 'off';
            app.Checksummodulo10EditField_2Label = uilabel(app.TLELine2Layout);
            app.Checksummodulo10EditField_2Label.Text = 'Checksum (modulo 10)';
            app.Checksummodulo10EditField_2 = uieditfield(app.TLELine2Layout, 'text');
            app.Checksummodulo10EditField_2.Editable = 'off';
            app.SatelliteNameEditFieldLabel = uilabel(app.SatelliteInfoPanelLayout);
            app.SatelliteNameEditFieldLabel.Text = 'Satellite Name';
            app.SatelliteInfoNameEditField = uieditfield(app.SatelliteInfoPanelLayout, 'text');
            app.SatelliteInfoNameEditField.ValueChangedFcn = createCallbackFcn(app, @SatelliteInfoNameEditFieldValueChanged, true);
            app.SatelliteInfoAutogenerateChecksumsCheckBox = uicheckbox(app.SatelliteInfoPanelLayout);
            app.SatelliteInfoAutogenerateChecksumsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SatelliteInfoAutogenerateChecksumsCheckBoxValueChanged, true);
            app.SatelliteInfoAutogenerateChecksumsCheckBox.Text = 'Auto-generate checksums';
            app.SatelliteInfoIgnoreChecksumsCheckBox = uicheckbox(app.SatelliteInfoPanelLayout);
            app.SatelliteInfoIgnoreChecksumsCheckBox.Text = 'Ignore checksums';
            app.SatelliteTabUIHorizontalSlider = uislider(app.SatelliteTabLayout);
            app.SatelliteTabUIHorizontalSlider.Limits = [0 1];
            app.SatelliteTabUIHorizontalSlider.MajorTicks = [];
            app.SatelliteTabUIHorizontalSlider.MajorTickLabels = {''};
            app.SatelliteTabUIHorizontalSlider.MinorTicks = [];
            app.SatelliteTabUIHorizontalSlider.Value = 0.4;
            app.SatelliteTabUIVerticalSlider = uislider(app.SatelliteTabLayout);
            app.SatelliteTabUIVerticalSlider.Limits = [0 1];
            app.SatelliteTabUIVerticalSlider.MajorTicks = [];
            app.SatelliteTabUIVerticalSlider.MajorTickLabels = {};
            app.SatelliteTabUIVerticalSlider.MinorTicks = [];
            app.SatelliteTabUIVerticalSlider.Value = 0.5;
            app.SatelliteTabStatusLabel = uilabel(app.SatelliteTabLayout);
            app.SatelliteTabStatusLabel.Text = '';
            app.LoadedSatellitesPanel = uipanel(app.SatelliteTabLayout);
            app.LoadedSatellitesPanel.Title = 'Loaded Satellites';
            app.LoadedSatellitesPanelLayout = uigridlayout(app.LoadedSatellitesPanel);
            app.LoadedSatellitesTable = uitable(app.LoadedSatellitesPanelLayout);
            app.LoadedSatellitesTable.ColumnName = {'Name'; 'Inclination (deg)'; 'RAAN (deg)'; 'Eccentricity'; 'Arg of Perigee (deg)'; 'Mean Motion (rev/day)'};
            app.LoadedSatellitesTable.RowName = {};
            app.LoadedSatellitesTable.SelectionType = 'row';
            app.LoadedSatellitesTable.CellSelectionCallback = createCallbackFcn(app, @LoadedSatellitesTableCellSelection, true);
            app.LoadedSatellitesLoadButton = uibutton(app.LoadedSatellitesPanelLayout, 'push');
            app.LoadedSatellitesLoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadedSatellitesLoadButtonPushed, true);
            app.LoadedSatellitesLoadButton.Text = 'Load...';
            app.LoadedSatellitesExportButton = uibutton(app.LoadedSatellitesPanelLayout, 'push');
            app.LoadedSatellitesExportButton.ButtonPushedFcn = createCallbackFcn(app, @LoadedSatellitesExportButtonPushed, true);
            app.LoadedSatellitesExportButton.Text = 'Export...';
            app.LoadedSatellitesDeleteButton = uibutton(app.LoadedSatellitesPanelLayout, 'push');
            app.LoadedSatellitesDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @LoadedSatellitesDeleteButtonPushed, true);
            app.LoadedSatellitesDeleteButton.Text = 'Delete';
            app.AddSatellitePanel = uipanel(app.SatelliteTabLayout);
            app.AddSatellitePanel.Title = 'Add Satellite';
            app.AddSatellitePanelLayout = uigridlayout(app.AddSatellitePanel);
            app.TLEFileEditFieldLabel = uilabel(app.AddSatellitePanelLayout);
            app.TLEFileEditFieldLabel.Text = 'TLE File';
            app.TLEFileEditField = uieditfield(app.AddSatellitePanelLayout, 'text');
            app.TLEFromFileOpenButton = uibutton(app.AddSatellitePanelLayout, 'push');
            app.TLEFromFileOpenButton.ButtonPushedFcn = createCallbackFcn(app, @TLEFromFileOpenButtonPushed, true);
            app.TLEFromFileOpenButton.Text = 'Open...';
            app.SearchTextAreaLabel = uilabel(app.AddSatellitePanelLayout);
            app.SearchTextAreaLabel.Text = 'Search';
            app.TLEFromFileSearchTextArea = uitextarea(app.AddSatellitePanelLayout);
            app.TLEFromURLLoadButton = uibutton(app.AddSatellitePanelLayout, 'push');
            app.TLEFromURLLoadButton.ButtonPushedFcn = createCallbackFcn(app, @TLEFromURLLoadButtonPushed, true);
            app.TLEFromURLLoadButton.Text = 'Load';
            app.TLEURLEditFieldLabel = uilabel(app.AddSatellitePanelLayout);
            app.TLEURLEditFieldLabel.Text = 'TLE URL';
            app.TLEFromURLEditField = uieditfield(app.AddSatellitePanelLayout, 'text');
            app.TLEFromURLEditField.Value = 'https://www.celestrak.com/NORAD/elements/active.txt';
            app.SearchTextArea_2Label = uilabel(app.AddSatellitePanelLayout);
            app.SearchTextArea_2Label.Text = 'Search';
            app.TLEFromURLSearchTextArea = uitextarea(app.AddSatellitePanelLayout);
            app.PresetDropDownLabel = uilabel(app.AddSatellitePanelLayout);
            app.PresetDropDownLabel.Text = 'Preset';
            app.SatellitePresetDropDown = uidropdown(app.AddSatellitePanelLayout);
            app.SatellitePresetDropDown.Items = {'None', 'Sun-synchronous', 'Geostationary'};
            app.SatellitePresetDropDown.Value = 'None';
            app.SatellitePresetAddButton = uibutton(app.AddSatellitePanelLayout, 'push');
            app.SatellitePresetAddButton.ButtonPushedFcn = createCallbackFcn(app, @SatellitePresetAddButtonPushed, true);
            app.SatellitePresetAddButton.Text = 'Add';
            app.PropagationTab = uitab(app.MasterTabGroup);
            app.PropagationTab.Title = 'Propagation';
            app.SGP4TabGridLayout = uigridlayout(app.PropagationTab);
            app.SGP4TablePanel = uipanel(app.SGP4TabGridLayout);
            app.SGP4TablePanel.Title = 'Propagation Data';
            app.SGP4TableGridLayout = uigridlayout(app.SGP4TablePanel);
            app.SGP4Table = uitable(app.SGP4TableGridLayout);
            app.SGP4Table.ColumnName = {'Time'; 'Coord 1'; 'Coord 2'; 'Coord 3'};
            app.SGP4Table.RowName = {};
            app.SGP4Table.ColumnSortable = true;
            app.SGP4TableShowStepIndexesCheckBox = uicheckbox(app.SGP4TableGridLayout);
            app.SGP4TableShowStepIndexesCheckBox.Text = 'Show Step Indexes';
            app.CoordinatesDropDownLabel = uilabel(app.SGP4TableGridLayout);
            app.CoordinatesDropDownLabel.Text = 'Coordinates';
            app.SGP4TableCoordinatesFormatDropDown = uidropdown(app.SGP4TableGridLayout);
            app.SGP4TableCoordinatesFormatDropDown.Items = {'LLA', 'GCRF', 'ECEF'};
            app.SGP4TableCoordinatesFormatDropDown.Value = 'LLA';
            app.UTCOffsetEditField_2Label = uilabel(app.SGP4TableGridLayout);
            app.UTCOffsetEditField_2Label.Text = 'UTC Offset ±';
            app.SGP4TableUTCOffsetEditField = uieditfield(app.SGP4TableGridLayout, 'text');
            app.SGP4TableUTCOffsetEditField.Value = '+00:00';
            app.TimestampDropDownLabel = uilabel(app.SGP4TableGridLayout);
            app.TimestampDropDownLabel.Text = 'Timestamp';
            app.SGP4TableTimestampFormatDropDown = uidropdown(app.SGP4TableGridLayout);
            app.SGP4TableTimestampFormatDropDown.Items = {'mm-dd-yyyy HH:MM:SS', 'mmm-dd-yyyy HH:MM:SS', 'yyyy-mm-dd HH:MM:SS', 'yyyy-mmm-dd HH:MM:SS', 'yyyymmddTHHMMSS', 'Julian Day', 'Modified Julian Day'};
            app.SGP4TableTimestampFormatDropDown.Value = 'yyyy-mm-dd HH:MM:SS';
            app.SGP4TableHighlightSAACheckBox = uicheckbox(app.SGP4TableGridLayout);
            app.SGP4TableHighlightSAACheckBox.Text = 'Highlight Keepout';
            app.SGP4TableHighlightSAACheckBox.Value = true;
            app.SGP4TableHighlightEclipseCheckBox = uicheckbox(app.SGP4TableGridLayout);
            app.SGP4TableHighlightEclipseCheckBox.Text = 'Highlight Eclipse';
            app.DisplayDropDownLabel = uilabel(app.SGP4TableGridLayout);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.SGP4TableGridLayout);
            app.DisplayDropDown.Items = {'All'};
            app.DisplayDropDown.Value = 'All';
            app.LoadButton = uibutton(app.SGP4TableGridLayout, 'push');
            app.LoadButton.Text = 'Load...';
            app.ExportButton = uibutton(app.SGP4TableGridLayout, 'push');
            app.ExportButton.Text = 'Export...';
            app.AddEphemerisButton = uibutton(app.SGP4TableGridLayout, 'push');
            app.AddEphemerisButton.Enable = 'off';
            app.AddEphemerisButton.Text = 'Add Ephemeris...';
            app.SGP4GroundtrackPlotPanel = uipanel(app.SGP4TabGridLayout);
            app.SGP4GroundtrackPlotPanel.Title = 'Groundtrack Plot';
            app.SGP4PropagatorOptionsPanel = uipanel(app.SGP4TabGridLayout);
            app.SGP4PropagatorOptionsPanel.Title = 'SGP4 Propagator Options';
            app.SGP4PropagatorOptionsPanelGridLayout = uigridlayout(app.SGP4PropagatorOptionsPanel);
            app.SGP4PropagatorPropagateButton = uibutton(app.SGP4PropagatorOptionsPanelGridLayout, 'push');
            app.SGP4PropagatorPropagateButton.ButtonPushedFcn = createCallbackFcn(app, @SGP4PropagatorPropagateButtonPushed, true);
            app.SGP4PropagatorPropagateButton.Tag = 'SGP4NewPropagationOptions';
            app.SGP4PropagatorPropagateButton.Enable = 'off';
            app.SGP4PropagatorPropagateButton.Text = 'Propagate';
            app.TimestepEditFieldLabel = uilabel(app.SGP4PropagatorOptionsPanelGridLayout);
            app.TimestepEditFieldLabel.Tag = 'SGP4NewPropagationOptions';
            app.TimestepEditFieldLabel.Text = 'Timestep';
            app.SGP4PropagatorTimestepEditField = uieditfield(app.SGP4PropagatorOptionsPanelGridLayout, 'numeric');
            app.SGP4PropagatorTimestepEditField.LowerLimitInclusive = 'off';
            app.SGP4PropagatorTimestepEditField.UpperLimitInclusive = 'off';
            app.SGP4PropagatorTimestepEditField.Limits = [0 Inf];
            app.SGP4PropagatorTimestepEditField.Tag = 'SGP4NewPropagationOptions';
            app.SGP4PropagatorTimestepEditField.Value = 5;
            app.EndTimeEditFieldLabel = uilabel(app.SGP4PropagatorOptionsPanelGridLayout);
            app.EndTimeEditFieldLabel.Tag = 'SGP4NewPropagationOptions';
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.SGP4PropagatorEndTimeEditField = uieditfield(app.SGP4PropagatorOptionsPanelGridLayout, 'text');
            app.SGP4PropagatorEndTimeEditField.Tag = 'SGP4NewPropagationOptions';
            app.SGP4PropagatorEndTimeEditField.Value = '00:00:00';
            app.StartTimeEditFieldLabel = uilabel(app.SGP4PropagatorOptionsPanelGridLayout);
            app.StartTimeEditFieldLabel.Tag = 'SGP4NewPropagationOptions';
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.SGP4PropagatorStartTimeEditField = uieditfield(app.SGP4PropagatorOptionsPanelGridLayout, 'text');
            app.SGP4PropagatorStartTimeEditField.Tag = 'SGP4NewPropagationOptions';
            app.SGP4PropagatorStartTimeEditField.Value = '00:00:00';
            app.SGP4PropagatorTimestepUnitsDropDown = uidropdown(app.SGP4PropagatorOptionsPanelGridLayout);
            app.SGP4PropagatorTimestepUnitsDropDown.Items = {'milliseconds', 'seconds', 'minutes', ''};
            app.SGP4PropagatorTimestepUnitsDropDown.Value = 'seconds';
            app.EndDateDatePickerLabel = uilabel(app.SGP4PropagatorOptionsPanelGridLayout);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.SGP4PropagatorEndDateDatePicker = uidatepicker(app.SGP4PropagatorOptionsPanelGridLayout);
            app.SGP4PropagatorEndDateDatePicker.DisplayFormat = 'MMM-dd-yyyy';
            app.StartDateDatePickerLabel = uilabel(app.SGP4PropagatorOptionsPanelGridLayout);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.SGP4PropagatorStartDateDatePicker = uidatepicker(app.SGP4PropagatorOptionsPanelGridLayout);
            app.SGP4PropagatorStartDateDatePicker.DisplayFormat = 'MMM-dd-yyyy';
            app.UTCOffsetLabel = uilabel(app.SGP4PropagatorOptionsPanelGridLayout);
            app.UTCOffsetLabel.Text = 'UTC Offset ±';
            app.SGP4PropagatorUTCOffsetEditField = uieditfield(app.SGP4PropagatorOptionsPanelGridLayout, 'text');
            app.SGP4PropagatorUTCOffsetEditField.Value = '+00:00';
            app.PropagatorDropDownLabel = uilabel(app.SGP4PropagatorOptionsPanelGridLayout);
            app.PropagatorDropDownLabel.Text = 'Propagator';
            app.PropagatorDropDown = uidropdown(app.SGP4PropagatorOptionsPanelGridLayout);
            app.PropagatorDropDown.Items = {'SGP4', 'SDP4', 'Two-Body Keplerian'};
            app.PropagatorDropDown.Value = 'SGP4';
            app.SGP4TabUIVerticalSlider = uislider(app.SGP4TabGridLayout);
            app.SGP4TabUIVerticalSlider.Limits = [0 1];
            app.SGP4TabUIVerticalSlider.MajorTicks = [];
            app.SGP4TabUIVerticalSlider.MajorTickLabels = {};
            app.SGP4TabUIVerticalSlider.MinorTicks = [];
            app.SGP4TabUIVerticalSlider.Value = 0.6;
            app.SGP4TabUIHorizontalSlider = uislider(app.SGP4TabGridLayout);
            app.SGP4TabUIHorizontalSlider.Limits = [0 1];
            app.SGP4TabUIHorizontalSlider.MajorTicks = [];
            app.SGP4TabUIHorizontalSlider.MajorTickLabels = {};
            app.SGP4TabUIHorizontalSlider.MinorTicks = [];
            app.SGP4TabUIHorizontalSlider.Value = 0.4;
            app.SGP4TabStatusLabel = uilabel(app.SGP4TabGridLayout);
            app.SGP4TabStatusLabel.Text = '';
            app.GroundStationsTab = uitab(app.MasterTabGroup);
            app.GroundStationsTab.Title = 'Ground Stations';
            app.GroundStationsTabGridLayout = uigridlayout(app.GroundStationsTab);
            app.GroundStationsInfoPanel = uipanel(app.GroundStationsTabGridLayout);
            app.GroundStationsInfoPanel.Title = 'Ground Stations Info';
            app.GroundStationsInfoPanelGridLayout = uigridlayout(app.GroundStationsInfoPanel);
            app.GroundStationsInfoTable = uitable(app.GroundStationsInfoPanelGridLayout);
            app.GroundStationsInfoTable.ColumnName = {'GS Name'; 'Latitude (deg)'; 'Longitude (deg)'; 'Altitude (m)'; 'Radio Type'; 'Priority'; 'ECEF X (km)'; 'ECEF Y (km)'; 'ECEF Z (km)'};
            app.GroundStationsInfoTable.RowName = {};
            app.GroundStationsInfoTable.ColumnSortable = true;
            app.GroundStationsInfoTableLoadButton = uibutton(app.GroundStationsInfoPanelGridLayout, 'push');
            app.GroundStationsInfoTableLoadButton.Text = 'Load...';
            app.GroundStationsInfoTableSaveAsButton = uibutton(app.GroundStationsInfoPanelGridLayout, 'push');
            app.GroundStationsInfoTableSaveAsButton.Text = 'Save As...';
            app.PassoverOptionsPanel = uipanel(app.GroundStationsTabGridLayout);
            app.PassoverOptionsPanel.Title = 'Passover Calculation Options';
            app.PassoverOptionsPanelGridLayout = uigridlayout(app.PassoverOptionsPanel);
            app.ElevationConstraintLabel = uilabel(app.PassoverOptionsPanelGridLayout);
            app.ElevationConstraintLabel.Text = 'Elevation Constraint';
            app.PassoverOptionsElevationConstraintEditField = uieditfield(app.PassoverOptionsPanelGridLayout, 'numeric');
            app.PassoverOptionsElevationConstraintEditField.Limits = [0 90];
            app.AzimuthConstraintLabel = uilabel(app.PassoverOptionsPanelGridLayout);
            app.AzimuthConstraintLabel.Text = 'Azimuth Constraint';
            app.PassoverOptionsAzimuthConstraintEditField = uieditfield(app.PassoverOptionsPanelGridLayout, 'numeric');
            app.PassoverOptionsAzimuthConstraintEditField.Limits = [0 360];
            app.PassoverOptionsIgnorePassoversCheckBox = uicheckbox(app.PassoverOptionsPanelGridLayout);
            app.PassoverOptionsIgnorePassoversCheckBox.Text = 'Ignore passovers shorter than...';
            app.PassoverOptionsCalculatePassoversButton = uibutton(app.PassoverOptionsPanelGridLayout, 'push');
            app.PassoverOptionsCalculatePassoversButton.Enable = 'off';
            app.PassoverOptionsCalculatePassoversButton.Text = 'Calculate Passovers';
            app.PassoverOptionsAzimuthConstraintUnitsDropDown = uidropdown(app.PassoverOptionsPanelGridLayout);
            app.PassoverOptionsAzimuthConstraintUnitsDropDown.Items = {'arcseconds', 'arcminutes', 'degrees'};
            app.PassoverOptionsAzimuthConstraintUnitsDropDown.Value = 'degrees';
            app.PassoverOptionsElevationConstraintUnitsDropDown = uidropdown(app.PassoverOptionsPanelGridLayout);
            app.PassoverOptionsElevationConstraintUnitsDropDown.Items = {'arcseconds', 'arcminutes', 'degrees'};
            app.PassoverOptionsElevationConstraintUnitsDropDown.Value = 'degrees';
            app.PassoverOptionsIgnorePassoversEditField = uieditfield(app.PassoverOptionsPanelGridLayout, 'numeric');
            app.PassoverOptionsIgnorePassoversEditField.Limits = [0 Inf];
            app.PassoverOptionsIgnorePassoversEditField.Enable = 'off';
            app.PassoverOptionsIgnorePassoversUnitsDropDown = uidropdown(app.PassoverOptionsPanelGridLayout);
            app.PassoverOptionsIgnorePassoversUnitsDropDown.Items = {'milliseconds', 'seconds', 'minutes', 'hours', 'days'};
            app.PassoverOptionsIgnorePassoversUnitsDropDown.Enable = 'off';
            app.PassoverOptionsIgnorePassoversUnitsDropDown.Value = 'minutes';
            app.PassoverOptionsAdvancedOptionsButton = uibutton(app.PassoverOptionsPanelGridLayout, 'push');
            app.PassoverOptionsAdvancedOptionsButton.Visible = 'off';
            app.PassoverOptionsAdvancedOptionsButton.Text = 'Advanced...';
            app.PassoverEventsTablePanel = uipanel(app.GroundStationsTabGridLayout);
            app.PassoverEventsTablePanel.Title = 'Passover Events';
            app.PassoverEventsTablePanelGridLayout = uigridlayout(app.PassoverEventsTablePanel);
            app.PassoverEventsTable = uitable(app.PassoverEventsTablePanelGridLayout);
            app.PassoverEventsTable.ColumnName = {'GS Name'; 'Access #'; 'Start Time (UTC+00:00)'; 'End Time (UTC+00:00)'; 'Duration (min)'; 'GS Priority'; 'ECEF X (km)'; 'ECEF Y (km)'; 'ECEF Z (km)'};
            app.PassoverEventsTable.RowName = {};
            app.PassoverEventsTable.ColumnSortable = true;
            app.PassoverEventsTable.ColumnEditable = [true false false false true];
            app.PassoverEventsTable.Tooltip = {'Select an even to add to plan.'};
            app.ShowNonPassoverWindowsCheckBox = uicheckbox(app.PassoverEventsTablePanelGridLayout);
            app.ShowNonPassoverWindowsCheckBox.Text = 'Show Non-Passover Windows';
            app.TimestampDropDownLabel_2 = uilabel(app.PassoverEventsTablePanelGridLayout);
            app.TimestampDropDownLabel_2.Text = 'Timestamp';
            app.PassoverTableTimestampFormatDropDown = uidropdown(app.PassoverEventsTablePanelGridLayout);
            app.PassoverTableTimestampFormatDropDown.Items = {'mm-dd-yyyy HH:MM:SS', 'mmm-dd-yyyy HH:MM:SS', 'yyyy-mm-dd HH:MM:SS', 'yyyy-mmm-dd HH:MM:SS', 'yyyymmddTHHMMSS', 'Julian Day', 'Modified Julian Day'};
            app.PassoverTableTimestampFormatDropDown.Value = 'yyyy-mm-dd HH:MM:SS';
            app.UTCOffsetLabel_2 = uilabel(app.PassoverEventsTablePanelGridLayout);
            app.UTCOffsetLabel_2.Text = 'UTC Offset ±';
            app.PassoverTableUTCOffsetEditField = uieditfield(app.PassoverEventsTablePanelGridLayout, 'text');
            app.PassoverTableUTCOffsetEditField.Value = '+00:00';
            app.DisplayasDropDown_2Label = uilabel(app.PassoverEventsTablePanelGridLayout);
            app.DisplayasDropDown_2Label.Text = 'Display as';
            app.PassoverTableDisplayasDropDown = uidropdown(app.PassoverEventsTablePanelGridLayout);
            app.PassoverTableDisplayasDropDown.Items = {'event sequence', 'timesteps'};
            app.PassoverTableDisplayasDropDown.Value = 'event sequence';
            app.GridLayout14 = uigridlayout(app.PassoverEventsTablePanelGridLayout);
            app.PassoverEventsTableAddtoPlanButton = uibutton(app.GridLayout14, 'push');
            app.PassoverEventsTableAddtoPlanButton.Enable = 'off';
            app.PassoverEventsTableAddtoPlanButton.Text = 'Add to Plan';
            app.PassoverEventsTableAddAllButton = uibutton(app.GridLayout14, 'push');
            app.PassoverEventsTableAddAllButton.Enable = 'off';
            app.PassoverEventsTableAddAllButton.Text = 'Add All';
            app.PassoverTableShowStepIndexesCheckBox = uicheckbox(app.PassoverEventsTablePanelGridLayout);
            app.PassoverTableShowStepIndexesCheckBox.Text = 'Show Step Indexes';
            app.GroundStationsTabUIVerticalSlider = uislider(app.GroundStationsTabGridLayout);
            app.GroundStationsTabUIVerticalSlider.Limits = [0 1];
            app.GroundStationsTabUIVerticalSlider.MajorTicks = [];
            app.GroundStationsTabUIVerticalSlider.MajorTickLabels = {};
            app.GroundStationsTabUIVerticalSlider.MinorTicks = [];
            app.GroundStationsTabUIVerticalSlider.Value = 0.6;
            app.GroundStationsTabUIHorizontalSlider = uislider(app.GroundStationsTabGridLayout);
            app.GroundStationsTabUIHorizontalSlider.Limits = [0 1];
            app.GroundStationsTabUIHorizontalSlider.MajorTicks = [];
            app.GroundStationsTabUIHorizontalSlider.MajorTickLabels = {};
            app.GroundStationsTabUIHorizontalSlider.MinorTicks = [];
            app.GroundStationsTabUIHorizontalSlider.Value = 0.4;
            app.GroundStationsTabStatusLabel = uilabel(app.GroundStationsTabGridLayout);
            app.GroundStationsTabStatusLabel.Text = '';
            app.TargetsTab = uitab(app.MasterTabGroup);
            app.TargetsTab.Title = 'Targets';
            app.TargetsTabGridLayout = uigridlayout(app.TargetsTab);
            app.TargetInfoPanel = uipanel(app.TargetsTabGridLayout);
            app.TargetInfoPanel.Title = 'Target Info';
            app.TargetInfoPanelGridLayout = uigridlayout(app.TargetInfoPanel);
            app.TargetInfoTable = uitable(app.TargetInfoPanelGridLayout);
            app.TargetInfoTable.ColumnName = {'Name'; 'SNR'; 'Scientific Factor'; 'Importance Factor'; 'Priority'};
            app.TargetInfoTable.RowName = {};
            app.TargetInfoTable.ColumnSortable = true;
            app.TargetInfoLoadButton = uibutton(app.TargetInfoPanelGridLayout, 'push');
            app.TargetInfoLoadButton.Text = 'Load...';
            app.TargetInfoSaveAsButton = uibutton(app.TargetInfoPanelGridLayout, 'push');
            app.TargetInfoSaveAsButton.Text = 'Save As...';
            app.TargetInfoDeleteButton = uibutton(app.TargetInfoPanelGridLayout, 'push');
            app.TargetInfoDeleteButton.Text = 'Delete';
            app.TargetSchedulerOptionsPanel = uipanel(app.TargetsTabGridLayout);
            app.TargetSchedulerOptionsPanel.Title = 'Target Scheduler Options';
            app.TargetSchedulerOptionsPanelGridLayout = uigridlayout(app.TargetSchedulerOptionsPanel);
            app.TargetSchedulerCalculateVisibilityButton = uibutton(app.TargetSchedulerOptionsPanelGridLayout, 'push');
            app.TargetSchedulerCalculateVisibilityButton.Enable = 'off';
            app.TargetSchedulerCalculateVisibilityButton.Text = 'Calculate Visibility';
            app.TargetOptionsIgnoreWindowsCheckBox = uicheckbox(app.TargetSchedulerOptionsPanelGridLayout);
            app.TargetOptionsIgnoreWindowsCheckBox.Text = 'Ignore observation windows shorter than...';
            app.TargetOptionsIgnoreWindowsEditField = uieditfield(app.TargetSchedulerOptionsPanelGridLayout, 'numeric');
            app.TargetOptionsIgnoreWindowsEditField.Limits = [0 Inf];
            app.TargetOptionsIgnoreWindowsEditField.Enable = 'off';
            app.TargetOptionsIgnoreWindowsUnitsDropDown = uidropdown(app.TargetSchedulerOptionsPanelGridLayout);
            app.TargetOptionsIgnoreWindowsUnitsDropDown.Items = {'seconds', 'minutes', 'hours', 'days'};
            app.TargetOptionsIgnoreWindowsUnitsDropDown.Editable = 'on';
            app.TargetOptionsIgnoreWindowsUnitsDropDown.Enable = 'off';
            app.TargetOptionsIgnoreWindowsUnitsDropDown.Value = 'minutes';
            app.DayNightConstraintEditFieldLabel = uilabel(app.TargetSchedulerOptionsPanelGridLayout);
            app.DayNightConstraintEditFieldLabel.Text = 'Day/Night Constraint';
            app.TargetOptionsDayNightConstraintEditField = uieditfield(app.TargetSchedulerOptionsPanelGridLayout, 'numeric');
            app.ElevationConstraintEditFieldLabel = uilabel(app.TargetSchedulerOptionsPanelGridLayout);
            app.ElevationConstraintEditFieldLabel.Text = 'Elevation Constraint';
            app.TargetOptionsElevationConstraintEditField = uieditfield(app.TargetSchedulerOptionsPanelGridLayout, 'numeric');
            app.TargetOptionsElevationConstraintEditField.Limits = [0 Inf];
            app.TargetOptionsElevationConstraintEditField.Value = 15;
            app.MoonConstraintEditFieldLabel = uilabel(app.TargetSchedulerOptionsPanelGridLayout);
            app.MoonConstraintEditFieldLabel.Text = 'Moon Constraint';
            app.TargetOptionsMoonConstraintEditField = uieditfield(app.TargetSchedulerOptionsPanelGridLayout, 'numeric');
            app.TargetOptionsMoonConstraintEditField.Limits = [0 Inf];
            app.TargetOptionsMoonConstraintEditField.Value = 20;
            app.SunConstraintEditFieldLabel = uilabel(app.TargetSchedulerOptionsPanelGridLayout);
            app.SunConstraintEditFieldLabel.Text = 'Sun Constraint';
            app.TargetOptionsSunConstraintEditField = uieditfield(app.TargetSchedulerOptionsPanelGridLayout, 'numeric');
            app.TargetOptionsSunConstraintEditField.Limits = [0 Inf];
            app.TargetOptionsSunConstraintEditField.Value = 90;
            app.TargetObservationWindowsTablePanel = uipanel(app.TargetsTabGridLayout);
            app.TargetObservationWindowsTablePanel.Title = 'Target Observation Windows';
            app.TargetObservationWindowsTablePanelGridLayout = uigridlayout(app.TargetObservationWindowsTablePanel);
            app.TargetObservationWindowsTable = uitable(app.TargetObservationWindowsTablePanelGridLayout);
            app.TargetObservationWindowsTable.ColumnName = {'Target Name'; 'Window #'; 'Start Time (UTC)'; 'End Time (UTC)'; 'Duration (min)'; 'Window Type'; 'Target Priority'; 'RA (deg)'; 'Dec (deg)'; 'ECI X'; 'ECI Y'; 'ECI Z'};
            app.TargetObservationWindowsTable.RowName = {};
            app.TargetObservationWindowsTable.ColumnSortable = [true true true true true];
            app.TargetObservationWindowsTable.ColumnEditable = [true false false false true];
            app.TimestampDropDownLabel_3 = uilabel(app.TargetObservationWindowsTablePanelGridLayout);
            app.TimestampDropDownLabel_3.Text = 'Timestamp';
            app.TargetWindowsTableTimestampFormatDropDown = uidropdown(app.TargetObservationWindowsTablePanelGridLayout);
            app.TargetWindowsTableTimestampFormatDropDown.Items = {'mm-dd-yyyy HH:MM:SS', 'mmm-dd-yyyy HH:MM:SS', 'yyyy-mm-dd HH:MM:SS', 'yyyy-mmm-dd HH:MM:SS', 'yyyymmddTHHMMSS', 'Julian Day', 'Modified Julian Day'};
            app.TargetWindowsTableTimestampFormatDropDown.Value = 'yyyy-mm-dd HH:MM:SS';
            app.UTCOffsetLabel_3 = uilabel(app.TargetObservationWindowsTablePanelGridLayout);
            app.UTCOffsetLabel_3.Text = 'UTC Offset ±';
            app.TargetWindowsTableUTCOffsetEditField = uieditfield(app.TargetObservationWindowsTablePanelGridLayout, 'text');
            app.TargetWindowsTableUTCOffsetEditField.Value = '+00:00';
            app.DisplayasDropDownLabel = uilabel(app.TargetObservationWindowsTablePanelGridLayout);
            app.DisplayasDropDownLabel.Text = 'Display as';
            app.TargetWindowsTableDisplayasDropDown = uidropdown(app.TargetObservationWindowsTablePanelGridLayout);
            app.TargetWindowsTableDisplayasDropDown.Items = {'event sequence', 'timesteps'};
            app.TargetWindowsTableDisplayasDropDown.Enable = 'off';
            app.TargetWindowsTableDisplayasDropDown.Value = 'event sequence';
            app.TargetWindowsTableShowStepIndexesCheckBox = uicheckbox(app.TargetObservationWindowsTablePanelGridLayout);
            app.TargetWindowsTableShowStepIndexesCheckBox.Text = 'Show Step Indexes';
            app.TargetsTabUIHorizontalSlider = uislider(app.TargetsTabGridLayout);
            app.TargetsTabUIHorizontalSlider.Limits = [0 1];
            app.TargetsTabUIHorizontalSlider.MajorTicks = [];
            app.TargetsTabUIHorizontalSlider.MajorTickLabels = {};
            app.TargetsTabUIHorizontalSlider.MinorTicks = [];
            app.TargetsTabUIHorizontalSlider.Value = 0.4;
            app.TargetsTabUIVerticalSlider = uislider(app.TargetsTabGridLayout);
            app.TargetsTabUIVerticalSlider.Limits = [0 1];
            app.TargetsTabUIVerticalSlider.MajorTicks = [];
            app.TargetsTabUIVerticalSlider.MajorTickLabels = {};
            app.TargetsTabUIVerticalSlider.MinorTicks = [];
            app.TargetsTabUIVerticalSlider.Value = 0.6;
            app.TargetsTabStatusLabel = uilabel(app.TargetsTabGridLayout);
            app.TargetsTabStatusLabel.Text = '';
            app.ExposuresTab = uitab(app.MasterTabGroup);
            app.ExposuresTab.Title = 'Exposures';
            app.ExposuresTabGridLayout = uigridlayout(app.ExposuresTab);
            app.ExposuresAvailableWindowsTablePanel = uipanel(app.ExposuresTabGridLayout);
            app.ExposuresAvailableWindowsTablePanel.Title = 'Available Target Observation Windows';
            app.ExposuresAvailableWindowsTablePanelGridLayout = uigridlayout(app.ExposuresAvailableWindowsTablePanel);
            app.ExposuresAvailableWindowsTable = uitable(app.ExposuresAvailableWindowsTablePanelGridLayout);
            app.ExposuresAvailableWindowsTable.ColumnName = {'Target Name'; 'Window #'; 'Start Time (UTC)'; 'End Time (UTC)'; 'Duration (min)'; 'Window Type'; 'Target Priority'; 'RA (deg)'; 'Dec (deg)'; 'ECI X'; 'ECI Y'; 'ECI Z'};
            app.ExposuresAvailableWindowsTable.RowName = {};
            app.ExposuresAvailableWindowsTable.ColumnSortable = true;
            app.ExposuresAvailableIgnoreWindowsCheckBox = uicheckbox(app.ExposuresAvailableWindowsTablePanelGridLayout);
            app.ExposuresAvailableIgnoreWindowsCheckBox.Text = 'Ignore windows shorter than';
            app.ExposuresAvailableIgnoreWindowsEditField = uieditfield(app.ExposuresAvailableWindowsTablePanelGridLayout, 'numeric');
            app.ExposuresAvailableIgnoreWindowsEditField.Limits = [0 Inf];
            app.ExposuresAvailableIgnoreWindowsEditField.Enable = 'off';
            app.ExposuresAvailableIgnoreWindowsUnitsDropDown = uidropdown(app.ExposuresAvailableWindowsTablePanelGridLayout);
            app.ExposuresAvailableIgnoreWindowsUnitsDropDown.Items = {'seconds', 'minutes', 'hours', 'days'};
            app.ExposuresAvailableIgnoreWindowsUnitsDropDown.Editable = 'on';
            app.ExposuresAvailableIgnoreWindowsUnitsDropDown.Enable = 'off';
            app.ExposuresAvailableIgnoreWindowsUnitsDropDown.Value = 'minutes';
            app.ExposureOptionsPanel = uipanel(app.ExposuresTabGridLayout);
            app.ExposureOptionsPanel.Title = 'Exposure Options';
            app.ExposureOptionsPanelGridLayout = uigridlayout(app.ExposureOptionsPanel);
            app.ExposureOptionsAddExposureButton = uibutton(app.ExposureOptionsPanelGridLayout, 'push');
            app.ExposureOptionsAddExposureButton.Enable = 'off';
            app.ExposureOptionsAddExposureButton.Text = 'Add Exposure';
            app.StartTimeEditField_3Label = uilabel(app.ExposureOptionsPanelGridLayout);
            app.StartTimeEditField_3Label.Text = 'Start Time';
            app.ExposureOptionsStartTimeEditField = uieditfield(app.ExposureOptionsPanelGridLayout, 'text');
            app.ExposureOptionsStartTimeEditField.Value = '00:00:00';
            app.TargetNameEditFieldLabel = uilabel(app.ExposureOptionsPanelGridLayout);
            app.TargetNameEditFieldLabel.Text = 'Target Name';
            app.ExposureOptionsTargetNameEditField = uieditfield(app.ExposureOptionsPanelGridLayout, 'text');
            app.UTCLabel = uilabel(app.ExposureOptionsPanelGridLayout);
            app.UTCLabel.Text = 'UTC ±';
            app.ExposureOptionsUTCOffsetEditField = uieditfield(app.ExposureOptionsPanelGridLayout, 'text');
            app.ExposureOptionsUTCOffsetEditField.Value = '+00:00';
            app.StartDateDatePickerLabel_2 = uilabel(app.ExposureOptionsPanelGridLayout);
            app.StartDateDatePickerLabel_2.Text = 'Start Date';
            app.ExposureOptionsStartDateDatePicker = uidatepicker(app.ExposureOptionsPanelGridLayout);
            app.ExposureOptionsStartDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.ExposuresAddedTablePanel = uipanel(app.ExposuresTabGridLayout);
            app.ExposuresAddedTablePanel.Title = 'Exposures & Target Poiting';
            app.ExposuresAddedTablePanelGridLayout = uigridlayout(app.ExposuresAddedTablePanel);
            app.ExposuresAddedTable = uitable(app.ExposuresAddedTablePanelGridLayout);
            app.ExposuresAddedTable.ColumnName = {'Target Name'; 'Start Time (UTC)'; 'End Time (UTC)'; 'Duration (min)'; 'Priority (IT/OT)'; 'RA (deg)'; 'Dec (deg)'; 'u_x'; 'u_y'; 'u_z'; 'Target Idx'};
            app.ExposuresAddedTable.RowName = {};
            app.ExposuresAddedTable.ColumnSortable = true;
            app.ExposuresAddedTableRemoveButton = uibutton(app.ExposuresAddedTablePanelGridLayout, 'push');
            app.ExposuresAddedTableRemoveButton.Enable = 'off';
            app.ExposuresAddedTableRemoveButton.Text = 'Remove';
            app.ExposuresAddedShowStepIndexesCheckBox = uicheckbox(app.ExposuresAddedTablePanelGridLayout);
            app.ExposuresAddedShowStepIndexesCheckBox.Text = 'Show step indexes';
            app.TimestampDropDownLabel_4 = uilabel(app.ExposuresAddedTablePanelGridLayout);
            app.TimestampDropDownLabel_4.Text = 'Timestamp';
            app.ExposuresAddedTimestampFormatDropDown = uidropdown(app.ExposuresAddedTablePanelGridLayout);
            app.ExposuresAddedTimestampFormatDropDown.Items = {'mm-dd-yyyy HH:MM:SS', 'mmm-dd-yyyy HH:MM:SS', 'yyyy-mm-dd HH:MM:SS', 'yyyy-mmm-dd HH:MM:SS', 'yyyymmddTHHMMSS', 'Julian Day', 'Modified Julian Day'};
            app.ExposuresAddedTimestampFormatDropDown.Value = 'yyyy-mm-dd HH:MM:SS';
            app.UTCOffsetLabel_4 = uilabel(app.ExposuresAddedTablePanelGridLayout);
            app.UTCOffsetLabel_4.Text = 'UTC Offset ±';
            app.ExposuresAddedUTCOffsetEditField = uieditfield(app.ExposuresAddedTablePanelGridLayout, 'text');
            app.ExposuresAddedUTCOffsetEditField.Value = '+00:00';
            app.DisplayasDropDown_3Label = uilabel(app.ExposuresAddedTablePanelGridLayout);
            app.DisplayasDropDown_3Label.Text = 'Display as';
            app.ExposuresAddedDisplayasDropDown = uidropdown(app.ExposuresAddedTablePanelGridLayout);
            app.ExposuresAddedDisplayasDropDown.Items = {'event sequence', 'timesteps'};
            app.ExposuresAddedDisplayasDropDown.Value = 'event sequence';
            app.Slider = uislider(app.ExposuresTabGridLayout);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {};
            app.Slider.MinorTicks = [];
            app.Slider.Value = 0.6;
            app.Slider_2 = uislider(app.ExposuresTabGridLayout);
            app.Slider_2.Limits = [0 1];
            app.Slider_2.MajorTicks = [];
            app.Slider_2.MajorTickLabels = {};
            app.Slider_2.MinorTicks = [];
            app.Slider_2.Value = 0.5;
            app.ExposuresTabStatusLabel = uilabel(app.ExposuresTabGridLayout);
            app.ExposuresTabStatusLabel.Text = '';
            app.ConflictResolutionTab = uitab(app.MasterTabGroup);
            app.ConflictResolutionTab.Title = 'Conflict Resolution';
            app.ConflictResolutionTabGridLayout = uigridlayout(app.ConflictResolutionTab);
            app.ConflictsPanel = uipanel(app.ConflictResolutionTabGridLayout);
            app.ConflictsPanel.Title = 'Conflicts';
            app.ConflictResolutionUnderConstructionPanelGridLayout = uigridlayout(app.ConflictsPanel);
            app.ConflictTable = uitable(app.ConflictResolutionUnderConstructionPanelGridLayout);
            app.ConflictTable.ColumnName = {'Title'; 'Start Time'; 'End Time'; 'Duration'; 'Event Type'; 'Event Priority'; 'Subpriority'; 'Command'; 'Parameters'};
            app.ConflictTable.RowName = {};
            app.ConflictTable.ColumnSortable = true;
            app.ConflictTableDeleteEventButton = uibutton(app.ConflictResolutionUnderConstructionPanelGridLayout, 'push');
            app.ConflictTableDeleteEventButton.Enable = 'off';
            app.ConflictTableDeleteEventButton.Text = 'Delete Event';
            app.ConflictTableShowStepIndexesCheckBox = uicheckbox(app.ConflictResolutionUnderConstructionPanelGridLayout);
            app.ConflictTableShowStepIndexesCheckBox.Text = 'Show Step Indexes';
            app.TimestampDropDownLabel_5 = uilabel(app.ConflictResolutionUnderConstructionPanelGridLayout);
            app.TimestampDropDownLabel_5.Text = 'Timestamp';
            app.ConflictTableTimestampFormatDropDown = uidropdown(app.ConflictResolutionUnderConstructionPanelGridLayout);
            app.ConflictTableTimestampFormatDropDown.Items = {'mm-dd-yyyy HH:MM:SS', 'mmm-dd-yyyy HH:MM:SS', 'yyyy-mm-dd HH:MM:SS', 'yyyy-mmm-dd HH:MM:SS', 'yyyymmddTHHMMSS', 'Julian Day', 'Modified Julian Day'};
            app.ConflictTableTimestampFormatDropDown.Value = 'yyyy-mm-dd HH:MM:SS';
            app.UTCOffsetLabel_5 = uilabel(app.ConflictResolutionUnderConstructionPanelGridLayout);
            app.UTCOffsetLabel_5.Text = 'UTC Offset ±';
            app.ConflictTableUTCOffsetEditField = uieditfield(app.ConflictResolutionUnderConstructionPanelGridLayout, 'text');
            app.ConflictTableUTCOffsetEditField.Value = '+00:00';
            app.FinalizePlanTab = uitab(app.MasterTabGroup);
            app.FinalizePlanTab.Title = 'Finalize Plan';
            app.FinalizePlanTabGridLayout = uigridlayout(app.FinalizePlanTab);
            app.FinalizePlanPlotPanel = uipanel(app.FinalizePlanTabGridLayout);
            app.FinalizePlanPlotPanel.Title = 'Plot View';
            app.FinalizePlanTablePanel = uipanel(app.FinalizePlanTabGridLayout);
            app.FinalizePlanTablePanel.Title = 'Full Plan Table (Events)';
            app.FinalizePlanTablePanelGridLayout = uigridlayout(app.FinalizePlanTablePanel);
            app.FinalizePlanRemoveButton = uibutton(app.FinalizePlanTablePanelGridLayout, 'push');
            app.FinalizePlanRemoveButton.Enable = 'off';
            app.FinalizePlanRemoveButton.Text = 'Remove Event';
            app.FinalizePlanUITable = uitable(app.FinalizePlanTablePanelGridLayout);
            app.FinalizePlanUITable.ColumnName = {'Title'; 'Start Time'; 'End Time'; 'Duration'; 'Event Type'; 'Event Priority'; 'Subpriority'; 'Command'; 'Parameters'};
            app.FinalizePlanUITable.RowName = {};
            app.FinalizePlanUITable.ColumnSortable = true;
            app.DisplayplanasDropDownLabel = uilabel(app.FinalizePlanTablePanelGridLayout);
            app.DisplayplanasDropDownLabel.Text = 'Display plan as';
            app.FinalizePlanDisplayplanasDropDown = uidropdown(app.FinalizePlanTablePanelGridLayout);
            app.FinalizePlanDisplayplanasDropDown.Items = {'event sequence', 'timesteps'};
            app.FinalizePlanDisplayplanasDropDown.Value = 'event sequence';
            app.TimestampDropDownLabel_6 = uilabel(app.FinalizePlanTablePanelGridLayout);
            app.TimestampDropDownLabel_6.Text = 'Timestamp';
            app.FinalizePlanTimestampFormatDropDown = uidropdown(app.FinalizePlanTablePanelGridLayout);
            app.FinalizePlanTimestampFormatDropDown.Items = {'mm-dd-yyyy HH:MM:SS', 'mmm-dd-yyyy HH:MM:SS', 'yyyy-mm-dd HH:MM:SS', 'yyyy-mmm-dd HH:MM:SS', 'yyyymmddTHHMMSS', 'Julian Day', 'Modified Julian Day'};
            app.FinalizePlanTimestampFormatDropDown.Value = 'yyyy-mm-dd HH:MM:SS';
            app.FinalizePlanShowUnallocatedTimesCheckBox = uicheckbox(app.FinalizePlanTablePanelGridLayout);
            app.FinalizePlanShowUnallocatedTimesCheckBox.Enable = 'off';
            app.FinalizePlanShowUnallocatedTimesCheckBox.Text = 'Show Unallocated Times';
            app.UTCOffsetEditField_2Label_2 = uilabel(app.FinalizePlanTablePanelGridLayout);
            app.UTCOffsetEditField_2Label_2.Text = 'UTC Offset ±';
            app.FinalizePlanUTCOffsetEditField = uieditfield(app.FinalizePlanTablePanelGridLayout, 'text');
            app.FinalizePlanUTCOffsetEditField.Value = '+00:00';
            app.FinalizePlanShowStepIndexesCheckBox = uicheckbox(app.FinalizePlanTablePanelGridLayout);
            app.FinalizePlanShowStepIndexesCheckBox.Text = 'Show Step Indexes';
            app.AddChargingEventsCheckBox = uicheckbox(app.FinalizePlanTablePanelGridLayout);
            app.AddChargingEventsCheckBox.Text = 'Add Charging Events';
            app.AddChargingEventsCheckBox.Value = true;
            app.FinalizePlanViewOptionsPanel = uipanel(app.FinalizePlanTabGridLayout);
            app.FinalizePlanViewOptionsPanel.Title = 'View Options';
            app.FinalizePlanUIVerticalSlider = uislider(app.FinalizePlanTabGridLayout);
            app.FinalizePlanUIVerticalSlider.Limits = [0 1];
            app.FinalizePlanUIVerticalSlider.MajorTicks = [];
            app.FinalizePlanUIVerticalSlider.MajorTickLabels = {};
            app.FinalizePlanUIVerticalSlider.MinorTicks = [];

            app.FinalizePlanUIVerticalSlider.Value = 0.25;
            app.FinalizePlanUIHorizontalSlider = uislider(app.FinalizePlanTabGridLayout);
            app.FinalizePlanUIHorizontalSlider.Limits = [0 1];
            app.FinalizePlanUIHorizontalSlider.MajorTicks = [];
            app.FinalizePlanUIHorizontalSlider.MajorTickLabels = {};
            app.FinalizePlanUIHorizontalSlider.MinorTicks = [];
            app.FinalizePlanUIHorizontalSlider.Value = 0.5;
            app.ExportJSONTab = uitab(app.MasterTabGroup);
            app.ExportJSONTab.Title = 'Export JSON';
            app.ExportJSONTabGridLayout = uigridlayout(app.ExportJSONTab);
            app.ExportJSONPanel = uipanel(app.ExportJSONTabGridLayout);
            app.ExportJSONPanel.Title = 'Export JSON';
            app.ExportJSONPanelGridLayout = uigridlayout(app.ExportJSONPanel);
            app.AuthorsEditFieldLabel = uilabel(app.ExportJSONPanelGridLayout);
            app.AuthorsEditFieldLabel.Text = 'Author(s)';
            app.ExportJSONAuthorsEditField = uieditfield(app.ExportJSONPanelGridLayout, 'text');
            app.ObservationPlanCommentsoptionalTextAreaLabel = uilabel(app.ExportJSONPanelGridLayout);
            app.ObservationPlanCommentsoptionalTextAreaLabel.Text = 'Observation Plan Comments (optional)';
            app.ExportJSONPlanCommentsoptionalTextArea = uitextarea(app.ExportJSONPanelGridLayout);
            app.ExportJSONButton = uibutton(app.ExportJSONPanelGridLayout, 'push');
            app.ExportJSONButton.Enable = 'off';
            app.ExportJSONButton.Text = 'Export';
            app.ExportJSONPreviewPanel = uipanel(app.ExportJSONTabGridLayout);
            app.ExportJSONPreviewPanel.Title = 'JSON Preview';
            app.ExportJSONPreviewGridLayout = uigridlayout(app.ExportJSONPreviewPanel);
            app.StoredCommandCommentsoptionalLabel = uilabel(app.ExportJSONPreviewGridLayout);
            app.StoredCommandCommentsoptionalLabel.Text = 'Stored Command Comments (optional)';
            app.ExportJSONCommandCommentsTextArea = uitextarea(app.ExportJSONPreviewGridLayout);
            app.ExportJSONPreview = uitextarea(app.ExportJSONPreviewGridLayout);
            app.StoredCommandDescriptionoptionalTextAreaLabel = uilabel(app.ExportJSONPreviewGridLayout);
            app.StoredCommandDescriptionoptionalTextAreaLabel.Text = 'Stored Command Description (optional)';
            app.ExportJSONCommandDescriptionTextArea = uitextarea(app.ExportJSONPreviewGridLayout);
            app.ExportJSONUIVerticalSlider = uislider(app.ExportJSONTabGridLayout);
            app.ExportJSONUIVerticalSlider.Limits = [0 1];
            app.ExportJSONUIVerticalSlider.MajorTicks = [];
            app.ExportJSONUIVerticalSlider.MajorTickLabels = {};
            app.ExportJSONUIVerticalSlider.MinorTicks = [];
            app.ExportJSONUIVerticalSlider.Value = 0.333;
            app.MasterUIScaleSlider = uislider(app.MasterGridLayout);
            app.MasterUIScaleSlider.Limits = [0 1];
            app.MasterUIScaleSlider.MajorTicks = [];
            app.MasterUIScaleSlider.MajorTickLabels = {};
            app.MasterUIScaleSlider.MinorTicks = [];
            app.MasterUIScaleSlider.Value = 0.75;
            app.MasterPlanOverviewPanel = uipanel(app.MasterGridLayout);
            app.MasterPlanOverviewPanel.Title = 'Plan Overview';
            app.MasterPlanGridLayout = uigridlayout(app.MasterPlanOverviewPanel);
            app.MasterPlanTable = uitable(app.MasterPlanGridLayout);
            app.MasterPlanTable.ColumnName = {'Title'; 'Start Time'; 'End Time'; 'Duration'; 'Event Type'; 'Event Priority'; 'Subpriority'; 'Command'; 'Parameters'};
            app.MasterPlanTable.RowName = {};
            app.MasterPlanTable.ColumnSortable = true;
            app.MasterPlanJSONPreview = uitextarea(app.MasterPlanGridLayout);
            app.MasterPlanJSONPreview.Editable = 'off';
            app.MasterPlanRemoveButton = uibutton(app.MasterPlanGridLayout, 'push');
            app.MasterPlanRemoveButton.Enable = 'off';
            app.MasterPlanRemoveButton.Text = 'Remove Event';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = satplan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end